classdef MLTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        CurrentDirectoryPanel         matlab.ui.container.Panel
        FilePathEditField             matlab.ui.control.EditField
        currentfileLabel              matlab.ui.control.Label
        OpenFilePanel                 matlab.ui.container.Panel
        OpenresultsButton             matlab.ui.control.Button
        OpeninputfileButton           matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        LeftPanel                     matlab.ui.container.Panel
        ResultsLabel_2                matlab.ui.control.Label
        FiltersPanel                  matlab.ui.container.Panel
        autofilterCheckBox            matlab.ui.control.CheckBox
        FilterButton                  matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        FilterByUITable               matlab.ui.control.Table
        InfoPanel                     matlab.ui.container.Panel
        InfoTable                     matlab.ui.control.Table
        InfoLabel                     matlab.ui.control.Label
        ResultsPanel                  matlab.ui.container.Panel
        ResultsLabel                  matlab.ui.control.Label
        ParameterToVaryLabel          matlab.ui.control.Label
        ResultsTable                  matlab.ui.control.Table
        InputFilesPanel               matlab.ui.container.Panel
        InputfilesLabel               matlab.ui.control.Label
        InputFilesTable               matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        DetectionTrackingTab          matlab.ui.container.Tab
        MiddleGridLayout              matlab.ui.container.GridLayout
        DTMiddleTopPanel              matlab.ui.container.Panel
        ExportfeaturesButton          matlab.ui.control.Button
        FrameControlPanel             matlab.ui.container.Panel
        FrameSlider                   matlab.ui.control.Slider
        FrameSliderLabel              matlab.ui.control.Label
        Stop                          matlab.ui.control.Button
        Play_Reverse                  matlab.ui.control.Button
        Play_Forward                  matlab.ui.control.Button
        frmSpinner                    matlab.ui.control.Spinner
        ScaleImageDisplayPanel        matlab.ui.container.Panel
        gammaSlider                   matlab.ui.control.Slider
        gammaSliderLabel              matlab.ui.control.Label
        maxSlider                     matlab.ui.control.Slider
        maxSliderLabel                matlab.ui.control.Label
        minSlider                     matlab.ui.control.Slider
        minSliderLabel                matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        DetectButton                  matlab.ui.control.Button
        ExportMovieButton             matlab.ui.control.Button
        FeatureLabelsPanel            matlab.ui.container.Panel
        MarkercolorLabel              matlab.ui.control.Label
        MarkerColorButton             matlab.ui.control.Button
        MarkersizeEditField           matlab.ui.control.NumericEditField
        MarkersizeEditFieldLabel      matlab.ui.control.Label
        FeaturelabelsLabel            matlab.ui.control.Label
        ShowlabelsCheckBox            matlab.ui.control.CheckBox
        FeaturesButtonGroup           matlab.ui.container.ButtonGroup
        FeaturesLabel                 matlab.ui.control.Label
        alltrackedButton              matlab.ui.control.RadioButton
        alldetectedButton             matlab.ui.control.RadioButton
        previewButton                 matlab.ui.control.RadioButton
        ImageDisplayButtonGroup       matlab.ui.container.ButtonGroup
        ImageLabel                    matlab.ui.control.Label
        BandPassButton                matlab.ui.control.RadioButton
        RawButton                     matlab.ui.control.RadioButton
        TrackButton                   matlab.ui.control.Button
        ParametersToVaryPanel         matlab.ui.container.Panel
        ParameterstovaryLabel         matlab.ui.control.Label
        stepEditField                 matlab.ui.control.NumericEditField
        stepsizeLabel                 matlab.ui.control.Label
        minEditField                  matlab.ui.control.NumericEditField
        minvalueLabel                 matlab.ui.control.Label
        maxEditField                  matlab.ui.control.NumericEditField
        maxvalueLabel                 matlab.ui.control.Label
        ParametertovaryDropDown       matlab.ui.control.DropDown
        FramesPanel                   matlab.ui.container.Panel
        FramestotrackLabel            matlab.ui.control.Label
        lastFrameEditField            matlab.ui.control.NumericEditField
        lastEditFieldLabel            matlab.ui.control.Label
        firstFrameEditField           matlab.ui.control.NumericEditField
        firstEditFieldLabel           matlab.ui.control.Label
        TrackingModePanel             matlab.ui.container.Panel
        TrackingmodeLabel             matlab.ui.control.Label
        IncludecompundtracksLabel     matlab.ui.control.Label
        GapclosingwindowSpinner       matlab.ui.control.Spinner
        GapclosingwindowSpinnerLabel  matlab.ui.control.Label
        CheckBox                      matlab.ui.control.CheckBox
        trackingmodeDropDown          matlab.ui.control.DropDown
        CalibrationPanel              matlab.ui.container.Panel
        CalibrationLabel              matlab.ui.control.Label
        FramessecomdEditField         matlab.ui.control.NumericEditField
        FramessecomdEditFieldLabel    matlab.ui.control.Label
        MicronspixelEditField         matlab.ui.control.NumericEditField
        MicronspixelEditFieldLabel    matlab.ui.control.Label
        DetectionParametersPanel      matlab.ui.container.Panel
        DetectionparametersLabel      matlab.ui.control.Label
        HaloSizeEditField             matlab.ui.control.NumericEditField
        HaloSizeEditFieldLabel        matlab.ui.control.Label
        FeatureSizeEditField          matlab.ui.control.NumericEditField
        FeatureSizeEditFieldLabel     matlab.ui.control.Label
        PeakIntensitySpinner          matlab.ui.control.Spinner
        TotalIntensitySpinner         matlab.ui.control.Spinner
        TotalIntensitySlider          matlab.ui.control.Slider
        TotalIntensitySliderLabel     matlab.ui.control.Label
        PeakIntensitySlider           matlab.ui.control.Slider
        PeakIntensitySliderLabel      matlab.ui.control.Label
        DTMiddleBottomPanel           matlab.ui.container.Panel
        OverlayAxes                   matlab.ui.control.UIAxes
        ImageAxes                     matlab.ui.control.UIAxes
        FeatureAxes                   matlab.ui.control.UIAxes
        AnalysisTab                   matlab.ui.container.Tab
        AnalysisGridLayout            matlab.ui.container.GridLayout
        LeftPlotControlPanel          matlab.ui.container.Panel
        Panel_2                       matlab.ui.container.Panel
        PlotButton                    matlab.ui.control.Button
        AutoreplotCheckBox            matlab.ui.control.CheckBox
        PlotScaleButtonGroup          matlab.ui.container.ButtonGroup
        PlotScaleLabel                matlab.ui.control.Label
        LinearButton                  matlab.ui.control.RadioButton
        LogButton                     matlab.ui.control.RadioButton
        PlotDestButtonGroup           matlab.ui.container.ButtonGroup
        PlotDestinationLabel          matlab.ui.control.Label
        floatingButton                matlab.ui.control.RadioButton
        embeddedButton                matlab.ui.control.RadioButton
        PlotParameterPanel            matlab.ui.container.Panel
        TauEditField                  matlab.ui.control.NumericEditField
        TauLabel                      matlab.ui.control.Label
        SGWindowEditField             matlab.ui.control.NumericEditField
        SmotthingWindowSizeLabel      matlab.ui.control.Label
        MaxTauEditField               matlab.ui.control.NumericEditField
        Tau_maxLabel                  matlab.ui.control.Label
        ParametersLabel               matlab.ui.control.Label
        PlotModeButtonGroup           matlab.ui.container.ButtonGroup
        PlotmodeLabel                 matlab.ui.control.Label
        meanpooledButton              matlab.ui.control.RadioButton
        allButton                     matlab.ui.control.RadioButton
        selectedButton                matlab.ui.control.RadioButton
        PlotButtonGroup               matlab.ui.container.ButtonGroup
        MSDvsTauButton                matlab.ui.control.ToggleButton
        CumDvsTButton                 matlab.ui.control.ToggleButton
        TimeplotsLabel                matlab.ui.control.Label
        MSDvsTauMontageButton         matlab.ui.control.ToggleButton
        DistributionsLabel            matlab.ui.control.Label
        SpdHistButton                 matlab.ui.control.ToggleButton
        MSDatTauHistButton            matlab.ui.control.ToggleButton
        DvsAlphaButton                matlab.ui.control.ToggleButton
        IntHistButton                 matlab.ui.control.ToggleButton
        DecayCurveButton              matlab.ui.control.ToggleButton
        LTHistButton                  matlab.ui.control.ToggleButton
        MontageLabel                  matlab.ui.control.Label
        PlotKymoButton                matlab.ui.control.ToggleButton
        XvsYButton                    matlab.ui.control.ToggleButton
        DvsTButton                    matlab.ui.control.ToggleButton
        CumDvsTMontageButton          matlab.ui.control.ToggleButton
        IntvsTButton                  matlab.ui.control.ToggleButton
        AxisPanel                     matlab.ui.container.Panel
        ManyAxisPanel                 matlab.ui.container.Panel
        SingleAxisPanel               matlab.ui.container.Panel
    end

    
    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  parameters for feature detection  %%%%%%%%%%%%%%%%%%%

        haloSize = 2;               % Size of the annular halo around each feature in which to measure local background intensity 
        featureSize = 3;            % radius in pixels of the target feature
        minPeakIntensity = 0;       % min peak intensity in bandpass flitered image to accept candidate features
        minIntegratedIntensity = 0; % min integrated intensity to addepct feature 
        previewedFeatures = [];     % set of detected features
        rejectedFeatures = [];      % set iof candidate features (greater than min peak intensity) 
                                    % that were rejected (less than min integrated intensity)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  paraneters for deaing with inpout images  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
        
        UIMode = 'noInputFile';
        currentFile = [];                   % full path to current input file
        currentPath = [];                   % current path
        baseName = [];                      % basename for input files
        currentSample = [];
        inputFiles = [];                    % list of input tif files matcjhing basename*.tif where * is pos integer
        trks = {};                          % cell array of trajectories found for all samples and sampled par value
        filteredTrks = [];
        allFeatures = {};                   % cell array of all features found for all samples and sampled parameter values
        featuresByFrame = {};               % cell array of all features grouped by frame
        trackedFeatures = {};               % cell array of tracked features found for all samples and sampled par value
        info = [];
        imgFiles = {};
        rawImage = [];                      % raw pixel data for current frame
        bpImage = [];                       % bandpass-filtered pixel data for current frame
        gray_256 = [];                      % base colormap for image display

        minmax = [];                        % current intensity limits for displayed image
        rawMaxMin = [];                     % current intensity limits for raw image
        bpMaxMin = [];                      % current intensity limits for bandpass-filtered image
        gamma = 1;                          % current gamma correction factor for displayed image
        rawGamma = 1;                       % current gamma correction factor for raw image
        bpGamma = 1;                        % current gamma correction factor for bandpass-filtered image

        minMarker = [];                     % current plot for min value marker on histogram display
        maxMarker = [];                     % current plot for max value marker on histogram display
        gammaCurve = [];                    % current plot for gamma correction curve on histogram display

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  control of tracking  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        trackingMode = 'merge and split';       % feature tracking mode (whether to consider merges and or splits
        gapClosingTimeWindow = 1;               % maximum allowed time gap (in frames) between a track segment end and a track segment start that allows linking them.
        gapClosingMergeSplit = 1;               % set to: 1 if merging and splitting considered, 2 if only merging considered, 3 if only splitting considered, 0 if neitherconsidered.
        includeCompoundTracks = 1;              % set to 1 if you want to extract segments of compound tracks in your analysis, 0 otherwise
        firstFrame = -1;                        % first frame of sequence to analyze
        lastFrame = -1;                         % last frame of sequence to analyze
        nFrames = [];                           % number of frames available for each sample file
        numFramesInTrackedInterval = 1000;       % Break sequence into smaller chunks to increase efficiency
      
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% sample variation in tracking parameters %%%%%%%%%%%%%%%%%%%%%%%%

        parameterToVary = 'none';               % name of parameter to vary
        currentParameterValue = -1;             % current value of parameter to vary
        minParameterValue = -1                  % min value of parameter to vary
        maxParameterValue = -1                  % max value of parameter to vary
        parameterStepSize = 0;                  % step size for variation in parameter values
        numParameterSteps = 0;                  % numnber of steps for  variation in parameter values
        parameterValues = [];                   % vector of parameter values to sample

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% show Features %%%%%%%%%%%%%%%%%%%%%%%%
        markerSize = 10;
        markerColor = [1,0,0];
        labelParticles = 0;
        labelOffset = [6,6];
        
        playDirection = 'forward';
        videoPlaying = 0;

        singleAxis  = [];
        manyAxes = [];

        autoPlot = false;
        
    end
    
    
    methods (Access = private)

        function replot(app) 
            
            plotMode = app.PlotModeButtonGroup.SelectedObject.Text;
            logScale =   (app.PlotScaleButtonGroup.SelectedObject == app.LogButton);

            switch app.PlotButtonGroup.SelectedObject
                case {app.LTHistButton,app.DecayCurveButton,app.IntHistButton,app.MSDatTauHistButton,app.SpdHistButton,app.DvsAlphaButton,app.CumDvsTButton,app.MSDvsTauButton}

                    plotMontage = 0;
                    if isequal(plotMode,'selected')
                        tracks = app.filteredTrks{app.ResultsTable.Selection};                
                    else
                        tracks = app.filteredTrks;
                    end
                    axes = configureIndividualPlots(app,tracks);

                case {app.MSDvsTauMontageButton,app.IntvsTButton,app.CumDvsTMontageButton,app.DvsTButton,app.XvsYButton,app.PlotKymoButton}

                    plotMontage = 1;
                    tracks = app.filteredTrks{app.ResultsTable.Selection};                
                    axes = configureMontagePlots(app,tracks);
            end               

            switch app.PlotButtonGroup.SelectedObject
                
                case app.LTHistButton     

                    plotLifetimeHistogram(axes,tracks,app.info,logScale,plotMode);

                case app.IntHistButton

                    plotIntensityHistogram(axes,tracks,plotMode);

                case app.SpdHistButton

                    plotSpeedHistogram(axes,tracks,app.info,app.SGWindowEditField.Value,plotMode);

                case app.MSDatTauHistButton    

                    plotMSDAtTau(axes,tracks,app.TauEditField.Value,app.info,plotMode);
                                                            
                case app.DvsAlphaButton

                    plotD_vs_Alpha(axes,tracks,app.MaxTauEditField.Value,app.info,plotMode)
                    
                case app.DecayCurveButton

                    plotReleaseCurves(axes,tracks,app.info, plotMode)

                case app.MSDvsTauButton

                    plotMSDVSTau(axes,tracks,app.info,logScale,plotMontage,plotMode);

                case app.CumDvsTButton
                    
                     plotCumDVSTau(axes,tracks,app.info, app.SGWindowEditField.Value, plotMontage)

                case app.IntvsTButton

                    plotIntensityVsTime(axes,tracks)
                    
                case app.MSDvsTauMontageButton

                    plotMSDVSTau(axes,tracks,app.info,logScale,plotMontage);

                case app.CumDvsTMontageButton
                    
                     plotCumDVSTau(axes,tracks,app.info, app.SGWindowEditField.Value, plotMontage)

                case app.DvsTButton

                    plotDvsT(axes,tracks)

                case app.XvsYButton

                    plotTrajectories(axes,tracks,plotMontage)

                case app.PlotKymoButton

                    plotKymographs(axes, im, tracks,SGWindow,pathWidth)
                    
                otherwise
            end   
        end


        function axes = configureMontagePlots(app,tracks)

            plotDestination = app.PlotDestButtonGroup.SelectedObject.Text;

            % use selected trajectories
            nTracks = size(tracks,2);
            nRows = floor(sqrt(nTracks));         %number of rows
            nCols = floor(nTracks/nRows) + 1;     %number of cols

            % Switch panels
            app.ManyAxisPanel.Visible = 'on';
            tmp = findobj(app.ManyAxisPanel, 'type', 'axes')
            delete(tmp);
            app.SingleAxisPanel.Visible = 'off';
            app.ManyAxisPanel.Position = [0 0 app.AxisPanel.Position(3) app.AxisPanel.Position(4)];

            % Set up axes
            axes = cell(nTracks,1);       

            if isequal(plotDestination,'floating')
                figure;
                for i=1:nTracks  
                    axes{i} = subplot(nRows,nCols,i);
                    cla(axes{i},'reset');
                end
            else
                for i=1:nTracks  
                    axes{i} = subplot(nRows,nCols,i,'Parent', app.ManyAxisPanel);
                    cla(axes{i},'reset');
                end
            end
        end

        function axes = configureIndividualPlots(app,tracks)

            plotMode = app.PlotModeButtonGroup.SelectedObject.Text;
            plotDestination = app.PlotDestButtonGroup.SelectedObject.Text;

            switch plotMode

                case 'selected'

                    tracks = app.filteredTrks{app.ResultsTable.Selection};

                    if isequal(plotDestination,'floating')
                        figure;
                        axes = gca;
                    else
                        % Switch panels
                        app.ManyAxisPanel.Visible = 'off';
                        app.SingleAxisPanel.Visible = 'on';
    
                        % Set up axes
                        if isempty(app.singleAxis)
                            app.singleAxis = uiaxes(app.SingleAxisPanel);
                        else
                            cla(app.singleAxis,'reset')
                        end
                        axes = app.singleAxis;
                        axes.Position = [20,20,app.SingleAxisPanel.Position(3)-40,app.SingleAxisPanel.Position(4)-40];
                    end

                case 'all'

                    if isequal(plotDestination,'float')
                        figure;

                        % Set up axes
                        numSamples = size(app.filteredTrks,1);
                        numParameters = size(app.filteredTrks,2);
                        axes = cell(numSamples,numParameters);
                        for i = 1:numSamples
                            for j = 1:numParameters
                                axes{i,j} = subplot(numSamples, numParameters, numParameters*(i-1) + j);
                                cla(axes{i,j},'reset');
                            end
                        end
                    else
                        % Switch panels
                        app.SingleAxisPanel.Visible = 'off';
                        app.ManyAxisPanel.Visible = 'on';
                        app.ManyAxisPanel.Position = [0 0 app.AxisPanel.Position(3) app.AxisPanel.Position(4)];
    
                        % Set up axes
                        numSamples = size(app.filteredTrks,1);
                        numParameters = size(app.filteredTrks,2);
                        axes = cell(numSamples,numParameters);
                        for i = 1:numSamples
                            for j = 1:numParameters
                                axes{i,j} = subplot(numSamples, numParameters, numParameters*(i-1) + j, 'Parent', app.ManyAxisPanel);
                                cla(axes{i,j},'reset');
                            end
                        end
                    end

                case 'mean/pooled' 
                   
                    if isequal(plotDestination,'float')
                        figure;

                        % Set up axes
                        numParameters = size(app.filteredTrks,2);
                        axes = cell(numParameters,1);
                        for j = 1:numParameters                          
                            axes{j} = subplot(1,numParameters,j);
                            cla(axes{j},'reset');
                        end
                    else
                        % Switch panels
                        app.SingleAxisPanel.Visible = 'off';
                        app.ManyAxisPanel.Visible = 'on';
                        app.ManyAxisPanel.Position = [0 0 app.AxisPanel.Position(3) app.AxisPanel.Position(4)];
    
                        % Set up axes
                        numParameters = size(app.filteredTrks,2);
                        axes = cell(numParameters,1);
                        for j = 1:numParameters                          
                            axes{j} = subplot(1,numParameters,j, 'Parent', app.ManyAxisPanel);
                            cla(axes{j},'reset');
                        end
                    end
            end
        end

        function [im] = readInputTiffiles(app,tifName,ffrm,lfrm, showProgress)

            if ~isfile(tifName)
                disp('choose a valid filename file.tif');
            else                
                 tifInfo = imfinfo(tifName);                 
                 nfrms = min(lfrm,length(tifInfo)) - ffrm + 1;
                  w = tifInfo(1).Width;
                  h = tifInfo(1).Height;
                  im = zeros(h,w,nfrms,'uint16');
                  if showProgress
                      d = uiprogressdlg(app.UIFigure,'Title','reading tif file...',...
                            'Message',['reading ' tifName]);
                      for i = 1:nfrms           
                          d.Value = double(i)/double(nfrms);
                          im(:,:,i) = imread(tifName,i+ffrm-1);
                      end
                  else
                      for i = 1:nfrms           
                          im(:,:,i) = imread(tifName,i+ffrm-1);
                      end
                  end
            end
        end
    
   
        function files = getFilesWithSameBasename(app, wd, bn)
                a=dir(wd);  % list contents in wd.
                b = {a(:).name};  % cell array of names
                files = b(contains(b,bn + digitsPattern + '.tif')); 
                files = transpose(files);
        end                                                                             

        function files = getAllTiffiles(app, wd)
                a=dir(wd);  % list contents in wd.
                b = {a(:).name};  % cell array of names
                files = b(contains(b,'.tif')); 
                files = transpose(files);
        end        

        function files = getAllFiles(app, wd)
                a=dir(wd);  % list contents in wd.
                files = {a(:).name};  % cell array of names
                files = transpose(files);
        end    

        function setCurrentSample(app,sample)
            app.currentSample = sample;
            app.currentFile = fullfile(app.currentPath,app.inputFiles{sample});
%             if isequal(app.InputFilesPanel.Enable,'on')
%                 app.InputFilesTable.Selection = sample;
%             end
            app.InputFilesTable.Selection = sample;
            updateFrameLimits(app);
        end


        function setUIMode(app,mode) 

            app.UIMode = mode;

            switch app.UIMode

                case 'noInputFile'

                    app.InputFilesPanel.Enable = 'off';
                    app.FiltersPanel.Enable = 'off';
                    app.InfoPanel.Enable = 'off';
                    app.ResultsPanel.Enable = 'off';
                    app.CalibrationPanel.Enable = 'off';
                    app.TrackingModePanel.Enable = 'off';
                    app.ParametersToVaryPanel.Enable = 'off';
                    app.DetectionParametersPanel.Enable = 'off';
                    app.FramesPanel.Enable = 'off';
                    app.ScaleImageDisplayPanel.Enable = 'off';
                    app.FrameControlPanel.Enable = 'off';
                    app.ImageDisplayButtonGroup.Enable = 'off'
                    app.FeaturesButtonGroup.Enable = 'off';
                    app.FeaturesButtonGroup.SelectedObject = app.previewButton;                 
                    app.FeatureLabelsPanel.Enable = 'off';
                    app.DetectButton.Enable ='off';
                    app.TrackButton.Enable ='off';
                    app.ExportMovieButton.Enable ='off';   


                    app.ImageAxes.Visible = 'off';
                    app.FeatureAxes.Visible = 'off';
                    app.OverlayAxes.Visible = 'off';
                
                case 'preDetectionTracking'

                    app.InputFilesPanel.Enable = 'on';
                    app.FiltersPanel.Enable = 'off';
                    app.InfoPanel.Enable = 'off';
                    app.ResultsPanel.Enable = 'off';
                    app.CalibrationPanel.Enable = 'on';
                    app.TrackingModePanel.Enable = 'on';
                    app.ParametersToVaryPanel.Enable = 'on';
                    app.DetectionParametersPanel.Enable = 'on';
                    app.FramesPanel.Enable = 'on';
                    app.ScaleImageDisplayPanel.Enable = 'on';
                    app.FrameControlPanel.Enable = 'on';
                    app.ImageDisplayButtonGroup.Enable = 'on'
                    app.FeaturesButtonGroup.Enable = 'off';
                    app.FeaturesButtonGroup.SelectedObject = app.previewButton;                 
                    app.FeatureLabelsPanel.Enable = 'off';
                    app.DetectButton.Enable ='on';
                    app.TrackButton.Enable ='on';
                    app.ExportMovieButton.Enable ='off';

                    app.ImageAxes.Visible = 'on';
                    app.FeatureAxes.Visible = 'on';
                    app.OverlayAxes.Visible = 'on';
                    app.ImageAxes.Position = [1,1,365,420];
                    app.FeatureAxes.Position = [365,1,365,420];
                    app.OverlayAxes.Position = [365,1,365,420];

                    app.resetTrackingDefaults();
  
                case 'allDetected'

                    app.InputFilesPanel.Enable = 'off';
                    app.FiltersPanel.Enable = 'on';
                    app.InfoPanel.Enable = 'on';
                    app.ResultsPanel.Enable = 'on';
                    app.CalibrationPanel.Enable = 'off';
                    app.TrackingModePanel.Enable = 'off';
                    app.ParametersToVaryPanel.Enable = 'off';
                    app.DetectionParametersPanel.Enable = 'off';
                    app.FramesPanel.Enable = 'off';
                    app.ScaleImageDisplayPanel.Enable = 'on';
                    app.FrameControlPanel.Enable = 'on';
                    app.ImageDisplayButtonGroup.Enable = 'on'
                    app.FeaturesButtonGroup.Enable = 'off';
                    app.FeaturesButtonGroup.SelectedObject = app.alldetectedButton;                 
                    app.FeatureLabelsPanel.Enable = 'on';
                    app.DetectButton.Enable ='off';
                    app.TrackButton.Enable ='off';
                    app.ExportMovieButton.Enable ='on';

                    app.ImageAxes.Visible = 'on';
                    cla(app.FeatureAxes);
                    cla(app.OverlayAxes);
                    app.FeatureAxes.Visible = 'off';
                    app.OverlayAxes.Visible = 'off';
                    app.ImageAxes.Position = [182,1,365,420];
                    app.FeatureAxes.Position = [365,1,365,420];
                    app.OverlayAxes.Position = [365,1,365,420];


                case 'allTracked'
                    
                    app.InputFilesPanel.Enable = 'off';
                    app.FiltersPanel.Enable = 'on';
                    app.InfoPanel.Enable = 'on';
                    app.ResultsPanel.Enable = 'on';
                    app.CalibrationPanel.Enable = 'off';
                    app.TrackingModePanel.Enable = 'off';
                    app.ParametersToVaryPanel.Enable = 'off';
                    app.DetectionParametersPanel.Enable = 'off';
                    app.FramesPanel.Enable = 'off';
                    app.ScaleImageDisplayPanel.Enable = 'on';
                    app.FrameControlPanel.Enable = 'on';
                    app.ImageDisplayButtonGroup.Enable = 'on'
                    app.FeaturesButtonGroup.Enable = 'off';
                    app.FeaturesButtonGroup.SelectedObject = app.alltrackedButton;                 
                    app.FeatureLabelsPanel.Enable = 'on';
                    app.DetectButton.Enable ='off';
                    app.TrackButton.Enable ='off';
                    app.ExportMovieButton.Enable ='on';


                    app.ImageAxes.Visible = 'on';
                    cla(app.FeatureAxes);
                    cla(app.OverlayAxes);
                    app.FeatureAxes.Visible = 'off';
                    app.OverlayAxes.Visible = 'off';
                    app.ImageAxes.Position = [182,1,365,420];
                    app.FeatureAxes.Position = [365,1,365,420];
                    app.OverlayAxes.Position = [365,1,365,420];

            end
        end


        function resetTrackingDefaults(app)
            app.trackingmodeDropDown.Value = 'merge and split';
            app.GapclosingwindowSpinner.Value = 1;
            app.CheckBox.Value = 0;
            app.FeatureSizeEditField.Value = 3;
            app.HaloSizeEditField.Value = 2;
            app.stepEditField.Value = 0;
            app.minEditField.Value = -1;
            app.maxEditField.Value = -1;
        end


        function clearPlotControls(app)
            app.L_minEditField.Visible = 'off';
            app.SGwindowsizeEditField.Visible = 'off';
            app.PlotMontageCheckBox.Visible = 'off';
            app.PlotmontageLabel.Visible = 'off';
            app.TauEditField.Visible = 'off';
            app.MaxTauEditField.Visible = 'off';
            app.MinTEditField.Visible = 'off';
            app.MaxTEditField.Visible = 'off';
        end

        
        % Called whenever the image or parameters that govern feature
        % selection have changed. Recomputes features.
        function featuresChanged(app)   
            app.previewedFeatures = findFeatures(app.rawImage,app.featureSize,app.minPeakIntensity,app.haloSize);
            if isempty(app.previewedFeatures)
                app.rejectedFeatures = [];
            else           
                cut = app.previewedFeatures(:,3) > app.minIntegratedIntensity;
                app.rejectedFeatures = app.previewedFeatures(~cut,:);
                app.previewedFeatures = app.previewedFeatures(cut,:);
            end
        end

        % update display slider values and limits whenever display mode changes
        function [] = displayModeChanged(app) 

            if app.RawButton.Value == true  % raw button selected
                app.gamma = app.rawGamma;
                app.minmax = app.rawMaxMin;
            else % band pass filtered button selected
                app.gamma = app.bpGamma;
                app.minmax = app.bpMaxMin;
            end      

            app.minSlider.Limits = app.rawMaxMin;
            app.maxSlider.Limits = app.rawMaxMin;
            app.minSlider.Value = app.rawMaxMin(1);
            app.maxSlider.Value = app.rawMaxMin(2);   
            app.gammaSlider.Value = app.gamma;   
            set(app.ImageAxes,'Colormap',app.gray_256.^app.gamma);
            set(app.FeatureAxes,'Colormap',app.gray_256.^app.gamma);
        end

        % call whenever the frame number changes to load in the right frame
        % and update features
        function [] = frameChanged(app)
            switch app.UIMode

                case 'preDetectionTracking'
                    app.rawImage = imread(app.currentFile,app.frmSpinner.Value);
                    if app.RawButton.Value == true  % raw button selected
                        app.bpImage = bpass(app.rawImage,1,app.featureSize);
                    end
                    app.featuresChanged();

                case {'allDetected','allTracked'}
                    if isempty(app.imgFiles)
                        app.rawImage = imread(app.currentFile,app.frmSpinner.Value);
                    else                       
                        app.rawImage = app.imgFiles{app.InputFilesTable.Selection}(:,:,app.frmSpinner.Value);
                    end
                    if app.BandPassButton.Value == true  % band pass button selected
                        app.bpImage = bpass(app.rawImage,1,app.featureSize);
                    end
            end                    
        end
        
        function [] = updateFrame(app)
            if isempty(app.rawImage) 
                return;
            end

            app.updateHistogram();
            app.updateImages();
            app.updateFeatures();
        end

        function [] = updateImages(app) 

            if app.RawButton.Value == true        
                img = app.rawImage;
            else
                img = app.bpImage;
            end
                
            imagesc(app.ImageAxes,img,app.minmax);

            if isequal(app.UIMode,'preDetectionTracking')
                imagesc(app.FeatureAxes,img,app.minmax);
            end

        end

        function [] = updateFeatures(app) 

            switch app.UIMode

                case 'preDetectionTracking'

                    % use overlay axes to draw found and rejected features over right hand image
                    cla(app.OverlayAxes);
                    set(app.OverlayAxes,'YDir', 'reverse');
                    set(app.OverlayAxes, 'xlim', get(app.FeatureAxes, 'xlim'),'ylim', get(app.FeatureAxes, 'ylim'));
                    hold(app.OverlayAxes,'on')
                    app.markerSize = 2*app.featureSize+1;
                    
                    if  ~isempty(app.previewedFeatures)
                        for c = 1:length(app.previewedFeatures(:,1))
                            cx = app.previewedFeatures(c,1);
                            cy = app.previewedFeatures(c,2);
                            plot(app.OverlayAxes,cx,cy,'ro','MarkerSize',app.markerSize)
                        end
                    end
                    if ~isempty(app.rejectedFeatures)
                        plot(app.OverlayAxes,app.rejectedFeatures(:,1),app.rejectedFeatures(:,2),'y.');
                    end
                    hold(app.OverlayAxes,'off')

                case {'allDetected','allTracked'}

                    if isequal(app.UIMode,'allDetected')
                        features = app.featuresByFrame{app.currentSample};
                    else
                        features = app.trackedFeatures{app.currentSample};
                    end
                        
                    
                    frm = app.frmSpinner.Value;
                    if ~isempty(features{frm})
                        hold(app.ImageAxes,'on');
                        for  i = 1:size(features{frm},1)
                            x = features{frm}(i,1);
                            y = features{frm}(i,2);
                            plot(app.ImageAxes,x,y,'Marker','o','MarkerEdgeColor', app.markerColor,'MarkerSize',app.markerSize);
                            if isequal(app.UIMode,'allTracked') & app.labelParticles
                                text(app.ImageAxes,x+app.labelOffset(1),y+app.labelOffset(2),num2str(features{frm}(i,3)), 'Color', 'g');
                            end
                        end
                        hold(app.ImageAxes,'off');
                    end
            end                    

        end

        function [] = updateHistogram(app)
            if app.RawButton.Value == true  % raw button selected
                [counts,edges] = histcounts(app.rawImage,256);
            else % band pass button selected
                [counts,edges] = histcounts(app.bpImage,256,'BinLimits',app.bpMaxMin);
            end

            histogram(app.UIAxes,'BinEdges',edges,'BinCounts',counts);
            y_lim = [0 max(counts)];
            ylim(app.UIAxes,y_lim);
            x_min = [app.minSlider.Value app.minSlider.Value];
            x_max = [app.maxSlider.Value app.maxSlider.Value];

            x_g = double(0:255)/255;
            y_g = y_lim(1) + (y_lim(2) - y_lim(1))*(x_g.^app.gammaSlider.Value);
            x_g = app.minSlider.Value + (app.maxSlider.Value - app.minSlider.Value)*x_g;

            hold(app.UIAxes,'on');
            app.minMarker = plot(app.UIAxes,x_min,y_lim,'Color','r','LineWidth',3);
            app.maxMarker = plot(app.UIAxes,x_max,y_lim,'Color','g','LineWidth',3);
            app.gammaCurve = plot(app.UIAxes,x_g,y_g,'Color','b','LineWidth',3);
            hold(app.UIAxes,'off');
                
        end

        function [] = playVideo(app) 

            app.videoPlaying = 1;
            
            while app.videoPlaying
                
                if isequal(app.playDirection,'forward')
                    nextFrm = app.frmSpinner.Value + 1;
                    if nextFrm > app.frmSpinner.Limits(2)
                        app.frmSpinner.Value = app.frmSpinner.Limits(1);
                    else
                        app.frmSpinner.Value = nextFrm;
                    end
                else
                    nextFrm = app.frmSpinner.Value - 1;
                    if nextFrm < app.frmSpinner.Limits(1)
                        app.frmSpinner.Value = app.frmSpinner.Limits(2);
                    else
                        app.frmSpinner.Value = nextFrm;
                    end                   
                end
                app.frameChanged();
                app.updateFrame();
                drawnow;
            end
        end


        function initTrackingFrameLimits(app)

             % read multiframe tif info
            nInputFiles = length(app.inputFiles);
            app.nFrames = zeros(1,nInputFiles);
            for i = 1:nInputFiles
                app.nFrames(i) = length(imfinfo(fullfile(app.currentPath, app.inputFiles{i})));
            end   
            maxFrames = max(app.nFrames);

            app.firstFrame = 1;
            app.firstFrameEditField.Limits = [1 maxFrames];
            app.firstFrameEditField.Value = 1;
            app.lastFrameEditField.Limits = [1 maxFrames];
            app.lastFrameEditField.Value = maxFrames;
        end
       
        function initFrame(app)

            if isequal(app.UIMode,'preDetectionTracking')

                fileInfo = imfinfo(app.currentFile);
                nFrms = length(fileInfo);
                app.frmSpinner.Limits = [1 nFrms];
                app.FrameSlider.Limits = [1 nFrms];      

            else
                app.frmSpinner.Limits = [app.info.firstFrame app.info.lastFrames(app.currentSample)];
                app.FrameSlider.Limits = [app.info.firstFrame app.info.lastFrames(app.currentSample)];     
                app.frmSpinner.Value =  app.info.firstFrame;
                app.FrameSlider.Value = app.info.firstFrame;     
            end

           
            % read image and construct bandpass filtered
            app.rawImage = imread(app.currentFile,app.frmSpinner.Value);
            app.bpImage = bpass(app.rawImage,1,app.featureSize);

            % reassign intensity limit
            app.rawMaxMin = [0 double(max(max(app.rawImage)))];
            app.bpMaxMin = [1 double(max(max(app.bpImage)))];


            if isequal(app.UIMode,'preDetectionTracking')
                app.minPeakIntensity = getIntensityThreshold(app.bpImage,0.999);
                app.PeakIntensitySpinner.Limits = app.bpMaxMin;
                app.PeakIntensitySpinner.Value = app.minPeakIntensity;
                app.PeakIntensitySlider.Limits = app.bpMaxMin;
                app.PeakIntensitySlider.Value = app.minPeakIntensity;
                          
                % estimate intensity thresholds
                app.previewedFeatures = findFeatures(app.rawImage,app.featureSize,app.minPeakIntensity,app.haloSize);
                app.minIntegratedIntensity = 0;
                app.TotalIntensitySpinner.Limits = [0 double(max(max(app.previewedFeatures(:,3))))];
                app.TotalIntensitySpinner.Value = 0;
                app.TotalIntensitySlider.Limits = [0 double(max(max(app.previewedFeatures(:,3))))];
                app.TotalIntensitySlider.Value = 0;
    
                % find previewedfeatures
                app.featuresChanged();   
            end


            displayModeChanged(app);
            updateFrame(app);
        end

        function updateInputFileTable(app)  
            app.inputFiles = app.getFilesWithSameBasename(app.currentPath,app.baseName);
            app.InputFilesTable.Data = app.inputFiles;   
            if ~isempty(app.currentSample)
                app.InputFilesTable.Selection = app.currentSample;
            end
        end

        
        function [] = updateInfoTable(app)

            infoData = cell(9,2);
            infoData{1,1} = 'frameRate';  
            infoData{1,2} = num2str(app.info.frameRate);  
            infoData{2,1} = 'pixelSize';  
            infoData{2,2} = num2str(app.info.pixelSize);  
            infoData{3,1} = 'number of Samples';  
            infoData{3,2} = app.info.numSamples;  
            infoData{4,1} = 'parameterToVary';  
            infoData{4,2} = app.info.parameterToVary;  
            infoData{5,1} = 'parameterValues';  
            s = '[';
            for i = 1:length(app.info.parameterValues)
                s = [s ',' num2str(app.info.parameterValues(i))];
            end
            s = [s ']'];
            infoData{5,2} = s;
            infoData{6,1} = 'gapClosingTimeWindow';  
            infoData{6,2} = app.info.gapClosingTimeWindow;  
            infoData{7,1} = 'gapClosingMergeSplit';  
            infoData{7,2} = app.info.gapClosingMergeSplit;  
            infoData{8,1} = 'firstFrame';  
            infoData{8,2} = num2str(app.info.firstFrame);  
            infoData{9,1} = 'numFramesInTrackedInterval';  
            infoData{9,2} = app.info.numFramesInTrackedInterval;  
            infoData{10,1} = 'lastFrames';  
            s = '[';
            for i = 1:length(app.info.lastFrames)
                s = [s ',' num2str(app.info.lastFrames(i))];
            end
            s = [s ']'];
            infoData{10,2} = s;  
            app.InfoTable.Data = infoData;
        end
            
 
        function [] = updateResultsTable(app) 

            numInputFiles = length(app.inputFiles);

            if isequal(app.info.parameterToVary,'none')
                app.ParameterToVaryLabel.Text = '';
                numParams = 1;
            else
                app.ParameterToVaryLabel.Text = app.info.parameterToVary;
                numParams = length(app.info.parameterValues);
            end
       
            resultsData = cell(numInputFiles,numParams);
            for i = 1:numInputFiles
                for j = 1:numParams
                    if isequal(app.UIMode,'allTracked') 
                        resultsData{i,j} = ['trks{'  num2str(i)  ','  num2str(j)  '}; n = ' num2str(length(app.trks{i,j}))];
                    else
                        if isequal(app.UIMode,'allDetected') 
                            resultsData{i,j} = ['features{'  num2str(i)  ','  num2str(j)  '}'];
                        end
                    end
                end
            end
            app.ResultsTable.Data = resultsData;
            if ~isempty(app.currentSample)
                app.ResultsTable.Selection = [app.currentSample,1]; 
            else
                app.ResultsTable.Selection = [1,1];
            end
        end


        function [] = createFilterTable(app) 
            name = {'Lifetime';'Speed';'Diffusivity';'Alpha'};
            min = [3;0;0;1];
            max = [inf;inf;inf;inf;];
            active = logical([0,0,0,0]).';
            app.FilterByUITable.Data = table(name,min,max,active);
        end

        function [] = filterData(app)  
                        
            app.filteredTrks = app.trks;
            data = app.FilterByUITable.Data;

            % determine number of samples and parameter values
            numSamples = size(app.trks,1);
            numParameters = size(app.trks,2);
            nrows = size(data,1);

            for i = 1:numSamples
                for j = 1:numParameters
                    tracks = app.trks{i,j};
        
                    for row = 1:nrows
                        switch string(data{row,1})
                            case 'Lifetime'
                                if data{row,4}
                                    min = data{row,2}; max = data{row,3};
                                    tracks = filterByLifetime(tracks,min,max);
                                    app.filteredTrks{i,j} = tracks;
                                end
                            case 'Diffusivity'
                            case 'Alpha'
                            case 'Intensity'
                            case 'Speed'
                        end
                    end
                end
            end
            app.trackedFeatures = cell(numSamples,numParameters);
            for i = 1:numSamples
                for j = 1:numParameters
                    app.trackedFeatures{i,j} = tracks2Features(app.filteredTrks{i,j},app.info.firstFrame,app.info.lastFrames(i));
                end
            end                 
            
            resultsData = cell(numSamples,numParameters);
            for i = 1:numSamples
                for j = 1:numParameters
                    resultsData{i,j} = ['trks{'  num2str(i)  ','  num2str(j)  '}; n = ' num2str(length(app.filteredTrks{i,j}))];
                end
            end
            app.ResultsTable.Data = resultsData;
        end


        function [] = updateFrameLimits(app) 
            if isempty(app.info)           
                limits = [1 length(imfinfo(app.currentFile))];
            else
                limits = [app.info.firstFrame,app.info.lastFrames(app.currentSample)];
            end

            app.frmSpinner.Limits = limits;
            app.FrameSlider.Limits = limits;
            app.FrameSlider.Value = limits(1);
            app.frmSpinner.Value = limits(1);
        end
    end
   
    methods (Access = public)

        function [costMatrices,gapCloseParam,kalmanFunctions,probDim,saveResults,verbose] = setupTracking(app,gapClosingTimeWindow,gapClosingMergeSplit)
            %% general gap closing parameters
            %
            % Copyrght (C) 2018, Danuser Lab - UTSouthwestern 
            %
            % This file is part of u-track.
            % 
            % u-track is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or
            % (at your option) any later version.
            % 
            % u-track is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            % GNU General Public License for more details.
            % 
            % You should have received a copy of the GNU General Public License
            % along with u-track.  If not, see <http://www.gnu.org/licenses/>.

            gapCloseParam.timeWindow = gapClosingTimeWindow;    
            gapCloseParam.mergeSplit = gapClosingMergeSplit;    
            
            gapCloseParam.minTrackLen = 2; %minimum length of track segments from linking to be used in gap closing.
            
            %optional input:
            gapCloseParam.diagnostics = 0; %1 to plot a histogram of gap lengths in the end; 0 or empty otherwise.
            
            %% cost matrix for frame-to-frame linking
            
            %function name
            costMatrices(1).funcName = 'costMatRandomDirectedSwitchingMotionLink';
            
            %parameters
            
            parameters.linearMotion = 0; %use linear motion Kalman filter.
            parameters.minSearchRadius = 2; %minimum allowed search radius. The search radius is calculated on the spot in the code given a feature's motion parameters. If it happens to be smaller than this minimum, it will be increased to the minimum.
            parameters.maxSearchRadius = 5; %maximum allowed search radius. Again, if a feature's calculated search radius is larger than this maximum, it will be reduced to this maximum.
            parameters.brownStdMult = 3; %multiplication factor to calculate search radius from standard deviation.
            
            parameters.useLocalDensity = 1; %1 if you want to expand the search radius of isolated features in the linking (initial tracking) step.
            parameters.nnWindow = gapCloseParam.timeWindow; %number of frames before the current one where you want to look to see a feature's nearest neighbor in order to decide how isolated it is (in the initial linking step).

            parameters.kalmanInitParam = []; %Kalman filter initialization parameters.
            % parameters.kalmanInitParam.searchRadiusFirstIteration = 10; %Kalman filter initialization parameters.
            
            %optional input
            parameters.diagnostics = []; %if you want to plot the histogram of linking distances up to certain frames, indicate their numbers; 0 or empty otherwise. Does not work for the first or last frame of a movie.
            
            costMatrices(1).parameters = parameters;
            clear parameters
            
            %% cost matrix for gap closing
            
            %function name
            costMatrices(2).funcName = 'costMatRandomDirectedSwitchingMotionCloseGaps';
            
            %parameters
            
            %needed all the time
            parameters.linearMotion = 0; %use linear motion Kalman filter.
            
            parameters.minSearchRadius = 2; %minimum allowed search radius.
            parameters.maxSearchRadius = 5; %maximum allowed search radius.
            parameters.brownStdMult = 3*ones(gapCloseParam.timeWindow,1); %multiplication factor to calculate Brownian search radius from standard deviation.
            
            parameters.brownScaling = [0.25 0.01]; %power for scaling the Brownian search radius with time, before and after timeReachConfB (next parameter).
            % parameters.timeReachConfB = 3; %before timeReachConfB, the search radius grows with time with the power in brownScaling(1); after timeReachConfB it grows with the power in brownScaling(2).
            parameters.timeReachConfB = gapCloseParam.timeWindow; %before timeReachConfB, the search radius grows with time with the power in brownScaling(1); after timeReachConfB it grows with the power in brownScaling(2).
            
            parameters.ampRatioLimit = [0.7 4]; %for merging and splitting. Minimum and maximum ratios between the intensity of a feature after merging/before splitting and the sum of the intensities of the 2 features that merge/split.
            
            parameters.lenForClassify = 5; %minimum track segment length to classify it as linear or random.
            
            parameters.useLocalDensity = 0; %1 if you want to expand the search radius of isolated features in the gap closing and merging/splitting step.
            parameters.nnWindow = gapCloseParam.timeWindow; %number of frames before/after the current one where you want to look for a track's nearest neighbor at its end/start (in the gap closing step).

            parameters.linStdMult = 3*ones(gapCloseParam.timeWindow,1); %multiplication factor to calculate linear search radius from standard deviation.
            
            parameters.linScaling = [1 0.01]; %power for scaling the linear search radius with time (similar to brownScaling).
            % parameters.timeReachConfL = 4; %similar to timeReachConfB, but for the linear part of the motion.
            parameters.timeReachConfL = gapCloseParam.timeWindow; %similar to timeReachConfB, but for the linear part of the motion.
            
            parameters.maxAngleVV = 30; %maximum angle between the directions of motion of two tracks that allows linking them (and thus closing a gap). Think of it as the equivalent of a searchRadius but for angles.
            
            %optional; if not input, 1 will be used (i.e. no penalty)
            parameters.gapPenalty = 1.5; %penalty for increasing temporary disappearance time (disappearing for n frames gets a penalty of gapPenalty^(n-1)).
            
            %optional; to calculate MS search radius
            %if not input, MS search radius will be the same as gap closing search radius
            parameters.resLimit = []; %resolution limit, which is generally equal to 3 * point spread function sigma.
            
            %NEW PARAMETER
            parameters.gapExcludeMS = 0; %flag to allow gaps to exclude merges and splits
            
            %NEW PARAMETER
            parameters.strategyBD = -1; %strategy to calculate birth and death cost
            
            costMatrices(2).parameters = parameters;
            clear parameters

            %% Kalman filter function names           
            kalmanFunctions.reserveMem  = 'kalmanResMemLM';
            kalmanFunctions.initialize  = 'kalmanInitLinearMotion';
            kalmanFunctions.calcGain    = 'kalmanGainLinearMotion';
            kalmanFunctions.timeReverse = 'kalmanReverseLinearMotion';
            
            %% additional input
            
            %don't save results
            saveResults = 0; 
            
            %verbose state
            verbose = 1;
            
            %problem dimension
            probDim = 2;
        end

        function [info,allFeatures, featuresByFrame, images] = detect(app)
            info.frameRate = app.FramessecomdEditField.Value;                % acquisition frame rate
            info.pixelSize = app.MicronspixelEditField.Value;                % original pixel size
            info.parameterToVary = app.parameterToVary;
            info.parameterValues = app.parameterValues;
            info.gapClosingTimeWindow = app.gapClosingTimeWindow;
            info.gapClosingMergeSplit = app.gapClosingMergeSplit;
            info.firstFrame = app.firstFrame;
            info.numFramesInTrackedInterval = app.numFramesInTrackedInterval;      
           
            % compute numbers of embryos and parameter values sample
            if isequal(app.parameterToVary,'none')
                info.numParameterValues = 1;
            else
                info.numParameterValues = length(app.parameterValues);
            end
            info.numSamples = length(app.inputFiles);
            info.lastFrames = zeros(1,info.numSamples);
    
            % Allocate storage for features
            allFeatures = cell(info.numSamples,info.numParameterValues);
            featuresByFrame = cell(info.numSamples,info.numParameterValues);
            images = cell(info.numSamples,1);


            % open progress dialog box
            d = uiprogressdlg(app.UIFigure,'Title','tracking',...
                'Message','Opening the application');

            for i = 1:info.numSamples  
        
                tifName = [app.currentPath  app.inputFiles{i}];
                info.lastFrames(i) = min(app.nFrames(i),app.lastFrameEditField.Value);   
                images{i} = readInputTiffiles(app,tifName,info.firstFrame,info.lastFrames(i), 0);
          
                for j = 1:info.numParameterValues
                    if isequal(app.parameterToVary,'min integrated intensity')
                        app.minIntegratedIntensity = app.parameterValues(j);
                    else %  app.parameterToVary = none
                        ;
                    end  

                    message = ['detecting sample ' num2str(i)];
                    if ~isequal(app.parameterToVary,'none')
                        message = [message ' for ' app.parameterToVary ' = ' num2str(app.parameterValues(j))];
                    end
                    d.Value = double(info.numParameterValues*(i-1) + j-1)/double(info.numSamples*info.numParameterValues);
                    d.Message = message;      

                    featuresByFrame{i,j} = detectOnce(images{i});
                    allFeatures{i,j} = features2table(featuresByFrame{i,j},info.firstFrame,info.lastFrames(i));

                end
            end

            % Close dialog box
            close(d)

            % save features and info
            save([app.currentPath app.baseName '_info.mat'],'info');
            save([app.currentPath app.baseName '_features.mat'],'allFeatures'); 
            save([app.currentPath app.baseName '_featuresbyframe.mat'],'featuresByFrame'); 

            
            % perform feature detection for one embryo
            function features = detectOnce(frms)  
                nFrms = size(frms,3);

                % compute features for all frames
                features = preTrack(frms,[1,nFrms], app.featureSize, app.minPeakIntensity,app.minIntegratedIntensity, app.haloSize);
            end
        end

        function exportFeatures(app) 
            if ~isempty(app.featuresByFrame)
                frame = transpose(app.info.firstFrame:app.info.lastFrames(app.currentSample));
                nFrms = length(frame);
                counts = zeros(nFrms,1);
                meanIntensity = zeros(nFrms,1);
                totalIntensity = zeros(nFrms,1);
                features = app.featuresByFrame{app.currentSample,1};
                for i = 1:length(features)
                    counts(i) = length(features{i});
                    meanIntensity(i) = mean(features{i}(:,3));
                    totalIntensity(i) = sum(features{i}(:,3));
                end
                
                T = table(frame,counts,meanIntensity,totalIntensity);
                filename = [app.currentPath app.baseName  num2str(app.currentSample) '_featureStats.csv'];
                writetable(T,filename)

                figure;
                subplot(1,3,1);
                plot(frame,counts);
                title('Counts')
                subplot(1,3,2);
                plot(frame,meanIntensity);
                title('Mean Intensity')
                subplot(1,3,3);
                plot(frame,totalIntensity);
                title('Total Intensity')
            end
        end
    
    
        function [trks,info,allFeatures,featuresByFrame,trackedFeatures, images] = track(app)
    
            info.frameRate = app.FramessecomdEditField.Value;                % acquisition frame rate
            info.pixelSize = app.MicronspixelEditField.Value;                % original pixel size
            info.parameterToVary = app.parameterToVary;
            info.parameterValues = app.parameterValues;
            info.gapClosingTimeWindow = app.gapClosingTimeWindow;
            info.gapClosingMergeSplit = app.gapClosingMergeSplit;
            info.minIntegratedIntensity = app.minIntegratedIntensity;
            info.firstFrame = app.firstFrame;
            info.numFramesInTrackedInterval = app.numFramesInTrackedInterval;      
           
            % compute numbers of embryos and parameter values sample
            if isequal(app.parameterToVary,'none')
                info.numParameterValues = 1;
            else
                info.numParameterValues = length(app.parameterValues);
            end
            info.numSamples = length(app.inputFiles);
            info.lastFrames = zeros(1,info.numSamples);
    
            % Allocate storage for trajectories and features
            trks = cell(info.numSamples,info.numParameterValues);
            allFeatures = cell(info.numSamples,info.numParameterValues);
            featuresByFrame = cell(info.numSamples,info.numParameterValues);
            trackedFeatures = cell(info.numSamples,info.numParameterValues);
            images = cell(info.numSamples,1);


            % open prgress dialog box
            d = uiprogressdlg(app.UIFigure,'Title','tracking',...
                'Message','Opening the application');

            for i = 1:info.numSamples  
        
                tifName = [app.currentPath  app.inputFiles{i}];
                info.lastFrames(i) = min(app.nFrames(i),app.lastFrameEditField.Value);   
                images{i} = readInputTiffiles(app,tifName,info.firstFrame,info.lastFrames(i), 0);
          
                for j = 1:info.numParameterValues
                    if isequal(app.parameterToVary,'min integrated intensity')
                        app.minIntegratedIntensity = app.parameterValues(j);
                    elseif isequal(app.parameterToVary,'gap closing window')
                        app.gapClosingTimeWindow = app.parameterValues(j);
                    else %  app.parameterToVary = none
                        ;
                    end  

                    message = ['tracking sample ' num2str(i)];
                    if ~isequal(app.parameterToVary,'none')
                        message = [message ' for ' app.parameterToVary ' = ' num2str(app.parameterValues(j))];
                    end
                    d.Value = double(info.numParameterValues*(i-1) + j-1)/double(info.numSamples*info.numParameterValues);
                    d.Message = message;      

                    [trks{i,j}, featuresByFrame{i,j}] = trackOnce(images{i});
                    trackedFeatures{i,j} = tracks2Features(trks{i,j},info.firstFrame,info.lastFrames(i));
                    allFeatures{i,j} = features2table(featuresByFrame{i,j},info.firstFrame,info.lastFrames(i));
                end
            end

            % Close dialog box
            close(d)

            % save trajectories and info
            save([app.currentPath app.baseName '_info.mat'],'info');
            save([app.currentPath app.baseName '_trks.mat'],'trks');   
            save([app.currentPath app.baseName '_features.mat'],'allFeatures'); 
            save([app.currentPath app.baseName '_featuresbyframe.mat'],'featuresByFrame'); 


            % perform tracking for one embryo
            function [trks,features] = trackOnce(frms)  
                nfrms = size(frms,3);
                trks = [];
                features = [];
 
                %  Compute tracking parameters (replaces call to scriptTrackGeneral)
                [costMatrices,gapCloseParam,kalmanFunctions,probDim,saveResults,verbose] = setupTracking(app,app.gapClosingTimeWindow,app.gapClosingMergeSplit);                

                for fFrm = 1:app.numFramesInTrackedInterval:nfrms
                    lFrm = min(fFrm+app.numFramesInTrackedInterval-1,nfrms);
                    lFrm_padded = min(fFrm+app.numFramesInTrackedInterval+2*app.gapClosingTimeWindow,nfrms);

                    % compute features for all frames
                    ftr = preTrack(frms,[fFrm,lFrm_padded], app.featureSize, app.minPeakIntensity,app.minIntegratedIntensity, app.haloSize);
                    movieInfo = Kilfoil_to_uTrack_particles(ftr);
                    [tracksFinal,~,~] = trackCloseGapsKalmanSparse(movieInfo,costMatrices,gapCloseParam,kalmanFunctions,probDim,saveResults,verbose);
                    tracks = uTrack_to_simple_traj(tracksFinal,fFrm,lFrm_padded,info,app.includeCompoundTracks);
                    features = [features ftr(1:lFrm-fFrm+1)];
                    trks = stitchTracks(trks,tracks,fFrm,app.gapClosingTimeWindow);
                end  
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 
            % Configure image axes
            app.gray_256 = gray(256);
            app.ImageAxes.Colormap = app.gray_256;
            axis(app.ImageAxes, 'image');
            app.FeatureAxes.Colormap = app.gray_256;
            axis(app.FeatureAxes, 'image');
            axis(app.OverlayAxes, 'image');
            disableDefaultInteractivity(app.ImageAxes);
            disableDefaultInteractivity(app.FeatureAxes);
            disableDefaultInteractivity(app.OverlayAxes);
            app.setUIMode('noInputFile');
            app.createFilterTable();
            app.ShowlabelsCheckBox.Value = app.labelParticles;

        end

        % Button pushed function: OpeninputfileButton
        function OpeninputfileButtonPushed(app, event)
            [file, path] = uigetfile('*.tif');

            if ~isequal(file,0)

                app.info = [];
                app.trks = {};
                app.filteredTrks = {};
                app.trackedFeatures = [];
                app.featuresByFrame = [];
                app.inputFiles = [];                   

                app.ResultsTable.Data = [];
                app.ParameterToVaryLabel.Text = '';
                app.InfoTable.Data = [];

                app.setUIMode('preDetectionTracking');

                app.currentFile = fullfile(path, file);
                app.currentPath = path;
                app.FilePathEditField.Value = path;
                app.baseName = extractBefore(file,digitsPattern(1,2) + ".tif");

                app.updateInputFileTable();
                
                % identify which of the input files is currently selected
                sample = [];
                for i = 1:length(app.inputFiles)
                    if isequal(file, app.inputFiles{i})
                        sample = i;
                    end
                end   
                app.setCurrentSample(sample);
                app.initTrackingFrameLimits();
                app.initFrame();

                app.TabGroup.SelectedTab = app.DetectionTrackingTab;

                app.UIFigure.Visible = 'off';   
                app.UIFigure.Visible = 'on';   
            end            
        end

        % Button pushed function: OpenresultsButton
        function OpenresultsButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            fullFile = fullfile(path, file);

            if ~isequal(fullFile,0)

                if contains(file,"_trks") | contains(file,"_featuresbyframe")

                    if contains(file,"_trks")
                        load(fullFile,'trks');
                        app.trks = trks;
                        app.filteredTrks = app.trks;

                        dum = replace(fullFile,"trks","featuresbyframe");
                        
                        if isfile('dum')
                            load(dum,'featuresByFrame');
                            app.featuresByFrame = featuresByFrame;
                        end
    
                        load(replace(fullFile,"trks","info"),'info');
                        app.info = info;
    
                        app.baseName = extractBefore(file,"_trks"); 
                        app.setUIMode('allTracked');
    
                        % calculate the tracked features
                        numSamples = size(app.filteredTrks,1);
                        numParameters = size(app.filteredTrks,2);
                        app.trackedFeatures = cell(numSamples,numParameters);
                        for i = 1:numSamples
                            for j = 1:numParameters
                                app.trackedFeatures{i,j} = tracks2Features(app.filteredTrks{i,j},app.info.firstFrame,app.info.lastFrames(i));
                            end
                        end
    
                    else
                        if contains(file,"_featuresbyframe")
                            load(fullFile,'featuresByFrame');                     
                            app.featuresByFrame = featuresByFrame;

                        
                            load(replace(fullFile,"featuresbyframe","info"),'info');
                            app.info = info;
                           
                            app.baseName = extractBefore(file,"_featuresbyframe");
                            app.setUIMode('allDetected');
                        end
    
                    end
    
                    app.currentPath = path;
                    app.updateInputFileTable()
                    app.updateResultsTable();
                    app.updateInfoTable();    
                    app.setCurrentSample(1);
                    app.initFrame();
                    app.TabGroup.SelectedTab = app.AnalysisTab;
                end
            end                        
        end

        % Value changed function: frmSpinner
        function frmSpinnerValueChanged(app, event)
            app.frameChanged();
            app.updateFrame();   
        end

        % Selection changed function: ImageDisplayButtonGroup
        function ImageDisplayButtonGroupSelectionChanged(app, event)
            app.displayModeChanged();
            app.updateHistogram();
            app.updateImages();
        end

        % Value changed function: FeatureSizeEditField
        function FeatureSizeEditFieldValueChanged(app, event)
            app.featureSize = app.FeatureSizeEditField.Value;
            app.frameChanged();
            app.updateFrame();           
        end

        % Value changed function: HaloSizeEditField
        function HaloSizeEditFieldValueChanged(app, event)
            app.haloSize = app.HaloSizeEditField.Value;
            app.frameChanged();
            app.updateFrame();           
        end

        % Value changing function: TotalIntensitySlider
        function TotalIntensitySliderValueChanging(app, event)
            app.minIntegratedIntensity = app.TotalIntensitySpinner.Step*fix(event.Value/app.TotalIntensitySpinner.Step);
            app.TotalIntensitySpinner.Value = app.minIntegratedIntensity;
        end

        % Value changed function: TotalIntensitySlider
        function TotalIntensitySliderValueChanged(app, event)
            app.minIntegratedIntensity = app.TotalIntensitySpinner.Step*fix(app.TotalIntensitySlider.Value/app.TotalIntensitySpinner.Step);
            app.TotalIntensitySpinner.Value = app.minIntegratedIntensity;
            app.featuresChanged();
            app.updateFrame();
        end

        % Value changed function: TotalIntensitySpinner
        function TotalIntensitySpinnerValueChanged(app, event)
            changedValue = app.TotalIntensitySpinner.Value;
            app.minIntegratedIntensity = changedValue;
            app.TotalIntensitySlider.Value = app.minIntegratedIntensity;
            app.featuresChanged();
            app.updateFrame();            
        end

        % Value changing function: PeakIntensitySlider
        function PeakIntensitySliderValueChanging(app, event)
            app.minPeakIntensity = app.PeakIntensitySpinner.Step*fix(event.Value/app.PeakIntensitySpinner.Step);
            app.PeakIntensitySpinner.Value = app.minPeakIntensity;
        end

        % Value changed function: PeakIntensitySlider
        function PeakIntensitySliderValueChanged(app, event)
            app.minPeakIntensity = app.PeakIntensitySpinner.Step*fix(app.PeakIntensitySlider.Value/app.PeakIntensitySpinner.Step);
            app.PeakIntensitySpinner.Value = app.minPeakIntensity;
            app.featuresChanged();
            app.updateFrame();
        end

        % Value changed function: PeakIntensitySpinner
        function PeakIntensitySpinnerValueChanged(app, event)
            changedValue = app.PeakIntensitySpinner.Value;
            app.minPeakIntensity = changedValue;
            app.PeakIntensitySlider.Value = app.minPeakIntensity;
            app.featuresChanged();
            app.updateFrame();            
            
        end

        % Value changed function: minSlider
        function minSliderValueChanged(app, event)
            app.updateImages();            
        end

        % Value changing function: minSlider
        function minSliderValueChanging(app, event)
            changingValue = event.Value;
            x = [changingValue changingValue];
            app.minmax(1) = changingValue;
            set(app.minMarker,'xdata',x);

            x_data = app.minmax(1) + (app.minmax(2) - app.minmax(1))*double(0:255)/255;
            set(app.gammaCurve,'xdata',x_data);

            app.updateImages();
            drawnow limitrate nocallbacks;
        end

        % Value changed function: maxSlider
        function maxSliderValueChanged(app, event)
            app.updateImages();            
        end

        % Value changing function: maxSlider
        function maxSliderValueChanging(app, event)
            changingValue = event.Value;
            x = [changingValue changingValue];
            app.minmax(2) = changingValue;
            set(app.maxMarker,'xdata',x);

            x_data = app.minmax(1) + (app.minmax(2) - app.minmax(1))*double(0:255)/255;
            set(app.gammaCurve,'xdata',x_data);

            app.updateImages();
            drawnow limitrate nocallbacks;          
        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
            app.gamma = event.Value;
            if app.RawButton.Value == true  % raw button selected
                app.rawGamma = app.gamma;
            else % band pass filtered button selected
                app.bpGamma = app.gamma;
            end  

            new_cmap = app.gray_256.^app.gamma;
            y_lim = app.UIAxes.YLim;
            y_data = y_lim(1) + (y_lim(2) - y_lim(1))*new_cmap(:,1);
            set(app.gammaCurve,'ydata',y_data);
            set(app.ImageAxes,'Colormap',new_cmap);
            set(app.FeatureAxes,'Colormap',new_cmap);
            drawnow limitrate nocallbacks;     
        end

        % Value changed function: gammaSlider
        function gammaSliderValueChanged(app, event)
            app.gamma = app.gammaSlider.Value;
            if app.RawButton.Value == true  % raw button selected
                app.rawGamma = app.gamma;
            else % band pass filtered button selected
                app.bpGamma = app.gamma;
            end            
            new_cmap = app.gray_256.^app.gamma;
            set(app.ImageAxes,'Colormap',new_cmap);
            set(app.FeatureAxes,'Colormap',new_cmap);
            app.updateImages();            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.frmSpinner.Value = fix(app.FrameSlider.Value);
            app.frameChanged();
            app.updateFrame();   
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            intVal = fix(event.Value);
            if app.frmSpinner.Value ~= intVal
                app.frmSpinner.Value = intVal;
                app.frmSpinner.Value
                app.frameChanged();
                app.updateFrame();   
                drawnow;          
%             drawnow limitrate nocallbacks;  
            end
        end

        % Value changed function: trackingmodeDropDown
        function trackingmodeDropDownValueChanged(app, event)
            app.trackingMode = app.trackingmodeDropDown.Value;
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.includeCompoundTracks = app.CheckBox.Value          
        end

        % Value changed function: GapclosingwindowSpinner
        function GapclosingwindowSpinnerValueChanged(app, event)
            app.gapClosingTimeWindow = app.GapclosingwindowSpinner.Value;            
        end

        % Value changed function: ParametertovaryDropDown
        function ParametertovaryDropDownValueChanged(app, event)
            app.parameterToVary = app.ParametertovaryDropDown.Value;   
            if isequal(app.parameterToVary,'min integrated intensity')
                app.currentParameterValue = app.minIntegratedIntensity;
                app.stepEditField.Editable = 'on';
            else
                if isequal(app.parameterToVary,'gap closing window')
                    app.currentParameterValue = app.gapClosingTimeWindow;
                    app.stepEditField.Editable = 'off';
                    app.stepEditField.Value = 1;
                    app.parameterStepSize = 1;
                else %  app.parameterToVary = none
                    app.currentParameterValue = -1;
                end
            end

            app.minEditField.Value = app.currentParameterValue;
            app.maxEditField.Value = app.currentParameterValue;
            app.minParameterValue = app.currentParameterValue; 
            app.maxParameterValue = app.currentParameterValue;
            app.parameterValues = app.minParameterValue:app.parameterStepSize:app.maxParameterValue;  

        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            if app.minEditField.Value <= app.maxParameterValue
                app.minParameterValue = app.minEditField.Value; 
            else
                app.minEditField.Value = app.minParameterValue;
            end
            app.parameterValues = app.minParameterValue:app.parameterStepSize:app.maxParameterValue;  
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
            if app.maxEditField.Value >= app.minParameterValue
                app.maxParameterValue = app.maxEditField.Value; 
            else
                app.maxEditField.Value = app.maxParameterValue;
            end
            app.parameterValues = app.minParameterValue:app.parameterStepSize:app.maxParameterValue; 
        end

        % Value changed function: stepEditField
        function stepEditFieldValueChanged(app, event)
            app.parameterStepSize = app.stepEditField.Value;
            app.parameterValues = app.minParameterValue:app.parameterStepSize:app.maxParameterValue;  
        end

        % Button pushed function: TrackButton
        function TrackButtonPushed(app, event)
            [app.trks,app.info,app.allFeatures,app.featuresByFrame,app.trackedFeatures,app.imgFiles] = app.track();
            
            app.filteredTrks = app.trks;
            app.setUIMode('allTracked');
            app.updateResultsTable();
            app.updateInfoTable();  
            app.updateFrameLimits();

        end

        % Callback function
        function numFramesPerTrackedIntervalValueChanged(app, event)
            app.numFramesInTrackedInterval = app.numFramesPerTrackedInterval.Value;          
        end

        % Button pushed function: ExportMovieButton
        function ExportMovieButtonPushed(app, event)
            app.currentSample = app.InputFilesTable.Selection;
            tifName = [app.currentPath  app.inputFiles{app.currentSample}];
            in = readInputTiffiles(app,tifName,app.info.firstFrame,app.info.lastFrames(app.currentSample), 0);
            features = app.trackedFeatures{app.currentSample};
            colorMap = app.gray_256.^app.gamma;
            movieName = [app.currentPath app.baseName '_tracked'];
            showParticles(in,features, colorMap, app.minmax, app.markerSize, app.labelParticles, app.labelOffset,movieName);
        end

        % Callback function
        function UseselectedasButtonPushed(app, event)
            selected = app.InputFilesTable.Selection;            
            if ~isempty(selected)
                selectedFiles = app.InputFilesTable.Data(selected);
            else
                selectedFiles = [];
            end
            if isequal(app.showWhat.Value,'Input File')
                app.inputFiles = selectedFiles;
            end
        end

        % Value changed function: firstFrameEditField
        function firstFrameEditFieldValueChanged(app, event)
            value = app.firstFrameEditField.Value;
            if value  < app.lastFrame
                app.firstFrame = value;
            else 
                app.firstFrameEditField.Value = app.firstFrame;
            end

        end

        % Value changed function: lastFrameEditField
        function lastFrameEditFieldValueChanged(app, event)
            value = app.lastFrameEditField.Value;
            if value >  app.firstFrame
                app.lastFrame = value;
            else 
                app.lastFrameEditField.Value = app.lastFrame;
            end
        end

        % Callback function: InputFilesTable
        function InputFilesTableSelectionChanged(app, event)
            sample = app.InputFilesTable.Selection;
            app.setCurrentSample(sample);
            app.initFrame();         
        end

        % Callback function: ResultsTable
        function ResultsTableSelectionChanged(app, event)
            selection = app.ResultsTable.Selection;
            app.setCurrentSample(selection(1));
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            app.videoPlaying = 0;
        end

        % Button pushed function: Play_Forward
        function Play_ForwardButtonPushed(app, event)
            app.playDirection = 'forward';
            if ~app.videoPlaying
                app.playVideo();
            end
        end

        % Button pushed function: Play_Reverse
        function Play_ReverseButtonPushed(app, event)
            app.playDirection = 'reverse';
            if ~app.videoPlaying
                app.playVideo();
            end            
        end

        % Button pushed function: MarkerColorButton
        function MarkerColorButtonPushed(app, event)
            app.markerColor = uisetcolor(app.MarkerColorButton);
        end

        % Value changed function: ShowlabelsCheckBox
        function ShowlabelsCheckBoxValueChanged(app, event)
            app.labelParticles = app.ShowlabelsCheckBox.Value;
        end

        % Value changed function: MarkersizeEditField
        function MarkersizeEditFieldValueChanged(app, event)
            app.markerSize = app.MarkersizeEditField.Value;            
        end

        % Value changed function: TauEditField
        function TauEditFieldValueChanged(app, event)
            if app.autoPlot
                app.replot();
            end            
        end

        % Callback function
        function SmoothingWindowSizeEditFieldValueChanged(app, event)
            if app.autoPlot
                app.replot();
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.replot();
        end

        % Selection changed function: PlotButtonGroup
        function PlotButtonGroupSelectionChanged(app, event)

            selectedButton = app.PlotButtonGroup.SelectedObject;

            set(app.PlotButtonGroup.Children, 'BackgroundColor', [0.96,0.96,0.96]);
            selectedButton.BackgroundColor = [0.78,1.00,0.89];

            app.SGWindowEditField.Visible = 'off';
            app.TauEditField.Visible = 'off';
            app.MaxTauEditField.Visible = 'off';
            app.PlotScaleButtonGroup.SelectedObject = app.LinearButton;
            app.PlotScaleButtonGroup.Enable = 'off';

            app.TauLabel.Visible = 'off';
            app.SmotthingWindowSizeLabel.Visible = 'off';
            app.Tau_maxLabel.Visible = 'off';


            switch selectedButton
                case {app.LTHistButton,app.DecayCurveButton,app.IntHistButton,app.SpdHistButton,app.DvsAlphaButton,app.MSDatTauHistButton,app.CumDvsTButton,app.MSDvsTauButton}
 
                   app.PlotModeButtonGroup.Enable = 'on'
             
                case {app.IntvsTButton,app.DvsTButton,app.CumDvsTMontageButton,app.XvsYButton,app.PlotKymoButton,app.MSDvsTauMontageButton}

                   app.PlotModeButtonGroup.Enable = 'off'

            end
                                       
            switch selectedButton

                case app.LTHistButton
                    app.PlotScaleButtonGroup.SelectedObject = app.LinearButton;
                    app.PlotScaleButtonGroup.Enable = 'on';

                case app.IntHistButton

                case app.SpdHistButton
                    app.SGWindowEditField.Visible = 'on';
                    app.SmotthingWindowSizeLabel.Visible = 'on';      
                                        
                case app.MSDatTauHistButton
                    app.TauEditField.Visible = 'on';
                    app.TauLabel.Visible = 'on';
                                           
                case app.DvsAlphaButton
                    app.MaxTauEditField.Visible = 'on';                                      
                    app.Tau_maxLabel.Visible = 'on';

                case app.DecayCurveButton

                case app.CumDvsTButton                  
                    app.SGWindowEditField.Visible = 'on';
                    app.SmotthingWindowSizeLabel.Visible = 'on';      

                case app.MSDvsTauButton
                    app.PlotScaleButtonGroup.SelectedObject = app.LogButton;
                    app.PlotScaleButtonGroup.Enable = 'on';

                case app.XvsYButton
                    app.SGWindowEditField.Visible = 'on';
                    app.SmotthingWindowSizeLabel.Visible = 'on';                    
                
                case app.IntvsTButton             

                case app.DvsTButton

                case app.PlotKymoButton
                    app.SGWindowEditField.Visible = 'on';
                    app.SmotthingWindowSizeLabel.Visible = 'on';      
                    
                case app.CumDvsTMontageButton                  
                    app.SGWindowEditField.Visible = 'on';
                    app.SmotthingWindowSizeLabel.Visible = 'on';      

                case app.MSDvsTauMontageButton
                    app.PlotScaleButtonGroup.SelectedObject = app.LogButton;
                    app.PlotScaleButtonGroup.Enable = 'on';

                otherwise
            end

            if app.autoPlot
                app.replot();
            end

        end

        % Selection changed function: PlotModeButtonGroup
        function PlotModeButtonGroupSelectionChanged2(app, event)
            if app.autoPlot
                app.replot();
            end

        end

        % Selection changed function: PlotScaleButtonGroup
        function PlotScaleButtonGroupSelectionChanged(app, event)
            if app.autoPlot
                app.replot();
            end

        end

        % Selection changed function: PlotDestButtonGroup
        function PlotDestButtonGroupSelectionChanged(app, event)
            if app.autoPlot
                app.replot();
            end
        end

        % Callback function
        function PlotModeButtonGroupSelectionChanged(app, event)
            if app.autoPlot
                app.replot();
            end
        end

        % Value changed function: AutoreplotCheckBox
        function AutoreplotCheckBoxValueChanged(app, event)
            app.autoPlot = app.AutoreplotCheckBox.Value;
            if app.autoPlot
                app.replot();
            end            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            nrows = size(app.FilterByUITable.Data,1);
            app.FilterByUITable.Data.active = false(nrows,1);
            app.filterData();
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            app.filterData();
        end

        % Cell edit callback: FilterByUITable
        function FilterByUITableCellEdit(app, event)
            if app.autofilterCheckBox.Value == 1
                app.filterData();
            end
        end

        % Value changed function: MaxTauEditField
        function MaxTauEditFieldValueChanged(app, event)
            if app.autoPlot
                app.replot();
            end            
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            [app.info,app.allFeatures, app.featuresByFrame,app.imgFiles] = app.detect();

            app.setUIMode('allDetected');
            app.updateResultsTable();
            app.updateInfoTable();  
            app.updateFrameLimits();

        end

        % Button pushed function: ExportfeaturesButton
        function ExportfeaturesButtonPushed(app, event)
            app.exportFeatures();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DetectionTrackingTab = uitab(app.TabGroup);
            app.DetectionTrackingTab.Title = 'Detection/Tracking';
            app.MiddleGridLayout = uigridlayout(app.DetectionTrackingTab);
            app.DTMiddleBottomPanel = uipanel(app.MiddleGridLayout);
            app.FeatureAxes = uiaxes(app.DTMiddleBottomPanel);
            app.FeatureAxes.XTick = [];
            app.FeatureAxes.YTick = [];
            app.FeatureAxes.Visible = 'off';
            app.ImageAxes = uiaxes(app.DTMiddleBottomPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.Visible = 'off';
            app.OverlayAxes = uiaxes(app.DTMiddleBottomPanel);
            app.OverlayAxes.XTick = [];
            app.OverlayAxes.YTick = [];
            app.OverlayAxes.Visible = 'off';
            app.DTMiddleTopPanel = uipanel(app.MiddleGridLayout);
            app.DetectionParametersPanel = uipanel(app.DTMiddleTopPanel);
            app.PeakIntensitySliderLabel = uilabel(app.DetectionParametersPanel);
            app.PeakIntensitySliderLabel.Text = 'Peak Intensity';
            app.PeakIntensitySlider = uislider(app.DetectionParametersPanel);
            app.PeakIntensitySlider.MajorTicks = [];
            app.PeakIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @PeakIntensitySliderValueChanged, true);
            app.PeakIntensitySlider.ValueChangingFcn = createCallbackFcn(app, @PeakIntensitySliderValueChanging, true);
            app.PeakIntensitySlider.MinorTicks = [];
            app.PeakIntensitySlider.Tag = 'minPeakIntensity';
            app.TotalIntensitySliderLabel = uilabel(app.DetectionParametersPanel);
            app.TotalIntensitySliderLabel.Text = 'Total Intensity';
            app.TotalIntensitySlider = uislider(app.DetectionParametersPanel);
            app.TotalIntensitySlider.MajorTicks = [];
            app.TotalIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @TotalIntensitySliderValueChanged, true);
            app.TotalIntensitySlider.ValueChangingFcn = createCallbackFcn(app, @TotalIntensitySliderValueChanging, true);
            app.TotalIntensitySlider.MinorTicks = [];
            app.TotalIntensitySlider.Tag = 'minPeakIntensity';
            app.TotalIntensitySpinner = uispinner(app.DetectionParametersPanel);
            app.TotalIntensitySpinner.Step = 10;
            app.TotalIntensitySpinner.Limits = [0 Inf];
            app.TotalIntensitySpinner.ValueDisplayFormat = '%11.5g';
            app.TotalIntensitySpinner.ValueChangedFcn = createCallbackFcn(app, @TotalIntensitySpinnerValueChanged, true);
            app.TotalIntensitySpinner.Value = 25000;
            app.PeakIntensitySpinner = uispinner(app.DetectionParametersPanel);
            app.PeakIntensitySpinner.Step = 10;
            app.PeakIntensitySpinner.Limits = [0 Inf];
            app.PeakIntensitySpinner.RoundFractionalValues = 'on';
            app.PeakIntensitySpinner.ValueDisplayFormat = '%11.5g';
            app.PeakIntensitySpinner.ValueChangedFcn = createCallbackFcn(app, @PeakIntensitySpinnerValueChanged, true);
            app.FeatureSizeEditFieldLabel = uilabel(app.DetectionParametersPanel);
            app.FeatureSizeEditFieldLabel.Text = 'Feature Size';
            app.FeatureSizeEditField = uieditfield(app.DetectionParametersPanel, 'numeric');
            app.FeatureSizeEditField.Limits = [1 10];
            app.FeatureSizeEditField.RoundFractionalValues = 'on';
            app.FeatureSizeEditField.ValueDisplayFormat = '%.0f';
            app.FeatureSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureSizeEditFieldValueChanged, true);
            app.FeatureSizeEditField.Value = 3;
            app.HaloSizeEditFieldLabel = uilabel(app.DetectionParametersPanel);
            app.HaloSizeEditFieldLabel.Text = 'Halo Size';
            app.HaloSizeEditField = uieditfield(app.DetectionParametersPanel, 'numeric');
            app.HaloSizeEditField.Limits = [1 10];
            app.HaloSizeEditField.RoundFractionalValues = 'on';
            app.HaloSizeEditField.ValueDisplayFormat = '%.0f';
            app.HaloSizeEditField.ValueChangedFcn = createCallbackFcn(app, @HaloSizeEditFieldValueChanged, true);
            app.HaloSizeEditField.Value = 2;
            app.DetectionparametersLabel = uilabel(app.DetectionParametersPanel);
            app.DetectionparametersLabel.Text = 'Detection parameters';
            app.CalibrationPanel = uipanel(app.DTMiddleTopPanel);
            app.MicronspixelEditFieldLabel = uilabel(app.CalibrationPanel);
            app.MicronspixelEditFieldLabel.Text = 'Microns/pixel';
            app.MicronspixelEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.MicronspixelEditField.Limits = [0 10];
            app.MicronspixelEditField.Value = 0.1;
            app.FramessecomdEditFieldLabel = uilabel(app.CalibrationPanel);
            app.FramessecomdEditFieldLabel.Text = 'Frames/secomd';
            app.FramessecomdEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.FramessecomdEditField.Limits = [0 100];
            app.FramessecomdEditField.Value = 10;
            app.CalibrationLabel = uilabel(app.CalibrationPanel);
            app.CalibrationLabel.Text = 'Calibration';
            app.TrackingModePanel = uipanel(app.DTMiddleTopPanel);
            app.trackingmodeDropDown = uidropdown(app.TrackingModePanel);
            app.trackingmodeDropDown.Items = {'merge and split', 'merge only', 'split only', 'neither merge nor split'};
            app.trackingmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @trackingmodeDropDownValueChanged, true);
            app.trackingmodeDropDown.Value = 'merge and split';
            app.CheckBox = uicheckbox(app.TrackingModePanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.GapclosingwindowSpinnerLabel = uilabel(app.TrackingModePanel);
            app.GapclosingwindowSpinnerLabel.Text = 'Gap closing window';
            app.GapclosingwindowSpinner = uispinner(app.TrackingModePanel);
            app.GapclosingwindowSpinner.Limits = [1 10];
            app.GapclosingwindowSpinner.ValueChangedFcn = createCallbackFcn(app, @GapclosingwindowSpinnerValueChanged, true);
            app.GapclosingwindowSpinner.Value = 1;
            app.IncludecompundtracksLabel = uilabel(app.TrackingModePanel);
            app.IncludecompundtracksLabel.Text = 'Include compund tracks';
            app.TrackingmodeLabel = uilabel(app.TrackingModePanel);
            app.TrackingmodeLabel.Text = 'Tracking mode';
            app.FramesPanel = uipanel(app.DTMiddleTopPanel);
            app.firstEditFieldLabel = uilabel(app.FramesPanel);
            app.firstEditFieldLabel.Text = 'first';
            app.firstFrameEditField = uieditfield(app.FramesPanel, 'numeric');
            app.firstFrameEditField.Limits = [0 Inf];
            app.firstFrameEditField.RoundFractionalValues = 'on';
            app.firstFrameEditField.ValueDisplayFormat = '%.0f';
            app.firstFrameEditField.ValueChangedFcn = createCallbackFcn(app, @firstFrameEditFieldValueChanged, true);
            app.lastEditFieldLabel = uilabel(app.FramesPanel);
            app.lastEditFieldLabel.Text = 'last';
            app.lastFrameEditField = uieditfield(app.FramesPanel, 'numeric');
            app.lastFrameEditField.Limits = [0 Inf];
            app.lastFrameEditField.RoundFractionalValues = 'on';
            app.lastFrameEditField.ValueDisplayFormat = '%.0f';
            app.lastFrameEditField.ValueChangedFcn = createCallbackFcn(app, @lastFrameEditFieldValueChanged, true);
            app.FramestotrackLabel = uilabel(app.FramesPanel);
            app.FramestotrackLabel.Text = 'Frames to track';
            app.ParametersToVaryPanel = uipanel(app.DTMiddleTopPanel);
            app.ParametertovaryDropDown = uidropdown(app.ParametersToVaryPanel);
            app.ParametertovaryDropDown.Items = {'none', 'gap closing window', 'min integrated intensity', ''};
            app.ParametertovaryDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametertovaryDropDownValueChanged, true);
            app.ParametertovaryDropDown.Value = 'none';
            app.maxvalueLabel = uilabel(app.ParametersToVaryPanel);
            app.maxvalueLabel.Text = {'max'; ''};
            app.maxEditField = uieditfield(app.ParametersToVaryPanel, 'numeric');
            app.maxEditField.Limits = [-1 Inf];
            app.maxEditField.RoundFractionalValues = 'on';
            app.maxEditField.ValueDisplayFormat = '%.0f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.maxEditField.Value = -1;
            app.minvalueLabel = uilabel(app.ParametersToVaryPanel);
            app.minvalueLabel.Text = 'min';
            app.minEditField = uieditfield(app.ParametersToVaryPanel, 'numeric');
            app.minEditField.Limits = [-1 Inf];
            app.minEditField.RoundFractionalValues = 'on';
            app.minEditField.ValueDisplayFormat = '%.0f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditField.Value = -1;
            app.stepsizeLabel = uilabel(app.ParametersToVaryPanel);
            app.stepsizeLabel.Text = {'step'; ''};
            app.stepEditField = uieditfield(app.ParametersToVaryPanel, 'numeric');
            app.stepEditField.Limits = [0 Inf];
            app.stepEditField.RoundFractionalValues = 'on';
            app.stepEditField.ValueDisplayFormat = '%.0f';
            app.stepEditField.ValueChangedFcn = createCallbackFcn(app, @stepEditFieldValueChanged, true);
            app.ParameterstovaryLabel = uilabel(app.ParametersToVaryPanel);
            app.ParameterstovaryLabel.Text = 'Parameters to vary';
            app.TrackButton = uibutton(app.DTMiddleTopPanel, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @TrackButtonPushed, true);
            app.TrackButton.Text = 'Track';
            app.ImageDisplayButtonGroup = uibuttongroup(app.DTMiddleTopPanel);
            app.ImageDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageDisplayButtonGroupSelectionChanged, true);
            app.RawButton = uiradiobutton(app.ImageDisplayButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.BandPassButton = uiradiobutton(app.ImageDisplayButtonGroup);
            app.BandPassButton.Text = 'Band Pass';
            app.ImageLabel = uilabel(app.ImageDisplayButtonGroup);
            app.ImageLabel.Text = 'Image';
            app.FeaturesButtonGroup = uibuttongroup(app.DTMiddleTopPanel);
            app.previewButton = uiradiobutton(app.FeaturesButtonGroup);
            app.previewButton.Text = 'preview';
            app.previewButton.Value = true;
            app.alldetectedButton = uiradiobutton(app.FeaturesButtonGroup);
            app.alldetectedButton.Text = 'all detected';
            app.alltrackedButton = uiradiobutton(app.FeaturesButtonGroup);
            app.alltrackedButton.Text = 'all tracked';
            app.FeaturesLabel = uilabel(app.FeaturesButtonGroup);
            app.FeaturesLabel.Text = 'Features';
            app.FeatureLabelsPanel = uipanel(app.DTMiddleTopPanel);
            app.ShowlabelsCheckBox = uicheckbox(app.FeatureLabelsPanel);
            app.ShowlabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlabelsCheckBoxValueChanged, true);
            app.ShowlabelsCheckBox.Text = '   Show labels ';
            app.FeaturelabelsLabel = uilabel(app.FeatureLabelsPanel);
            app.FeaturelabelsLabel.Text = 'Feature labels';
            app.MarkersizeEditFieldLabel = uilabel(app.FeatureLabelsPanel);
            app.MarkersizeEditFieldLabel.Text = 'Marker size';
            app.MarkersizeEditField = uieditfield(app.FeatureLabelsPanel, 'numeric');
            app.MarkersizeEditField.Limits = [0 30];
            app.MarkersizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersizeEditFieldValueChanged, true);
            app.MarkersizeEditField.Value = 15;
            app.MarkerColorButton = uibutton(app.FeatureLabelsPanel, 'push');
            app.MarkerColorButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerColorButtonPushed, true);
            app.MarkerColorButton.Text = '';
            app.MarkercolorLabel = uilabel(app.FeatureLabelsPanel);
            app.MarkercolorLabel.Text = 'Marker color';
            app.ExportMovieButton = uibutton(app.DTMiddleTopPanel, 'push');
            app.ExportMovieButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMovieButtonPushed, true);
            app.ExportMovieButton.Text = ' Export movie';
            app.DetectButton = uibutton(app.DTMiddleTopPanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.ScaleImageDisplayPanel = uipanel(app.DTMiddleTopPanel);
            app.UIAxes = uiaxes(app.ScaleImageDisplayPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.minSliderLabel = uilabel(app.ScaleImageDisplayPanel);
            app.minSliderLabel.Text = 'min';
            app.minSlider = uislider(app.ScaleImageDisplayPanel);
            app.minSlider.MajorTicks = [];
            app.minSlider.ValueChangedFcn = createCallbackFcn(app, @minSliderValueChanged, true);
            app.minSlider.ValueChangingFcn = createCallbackFcn(app, @minSliderValueChanging, true);
            app.minSlider.MinorTicks = [];
            app.maxSliderLabel = uilabel(app.ScaleImageDisplayPanel);
            app.maxSliderLabel.Text = 'max';
            app.maxSlider = uislider(app.ScaleImageDisplayPanel);
            app.maxSlider.MajorTicks = [];
            app.maxSlider.ValueChangedFcn = createCallbackFcn(app, @maxSliderValueChanged, true);
            app.maxSlider.ValueChangingFcn = createCallbackFcn(app, @maxSliderValueChanging, true);
            app.maxSlider.MinorTicks = [];
            app.gammaSliderLabel = uilabel(app.ScaleImageDisplayPanel);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.ScaleImageDisplayPanel);
            app.gammaSlider.Limits = [0 2];
            app.gammaSlider.MajorTicks = [];
            app.gammaSlider.ValueChangedFcn = createCallbackFcn(app, @gammaSliderValueChanged, true);
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.gammaSlider.MinorTicks = [];
            app.gammaSlider.Value = 1;
            app.FrameControlPanel = uipanel(app.DTMiddleTopPanel);
            app.frmSpinner = uispinner(app.FrameControlPanel);
            app.frmSpinner.Limits = [1 Inf];
            app.frmSpinner.RoundFractionalValues = 'on';
            app.frmSpinner.ValueDisplayFormat = '%11.5g';
            app.frmSpinner.ValueChangedFcn = createCallbackFcn(app, @frmSpinnerValueChanged, true);
            app.frmSpinner.Editable = 'off';
            app.frmSpinner.Value = 1;
            app.Play_Forward = uibutton(app.FrameControlPanel, 'push');
            app.Play_Forward.ButtonPushedFcn = createCallbackFcn(app, @Play_ForwardButtonPushed, true);
            app.Play_Forward.Icon = 'icons8-video-48.png';
            app.Play_Forward.Text = '';
            app.Play_Reverse = uibutton(app.FrameControlPanel, 'push');
            app.Play_Reverse.ButtonPushedFcn = createCallbackFcn(app, @Play_ReverseButtonPushed, true);
            app.Play_Reverse.Icon = 'icons8-video-reverse-48.png';
            app.Play_Reverse.Text = '';
            app.Stop = uibutton(app.FrameControlPanel, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Icon = 'icons8-stop-48.png';
            app.Stop.Text = '';
            app.FrameSliderLabel = uilabel(app.FrameControlPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.FrameControlPanel);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.MinorTicks = [];
            app.ExportfeaturesButton = uibutton(app.DTMiddleTopPanel, 'push');
            app.ExportfeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfeaturesButtonPushed, true);
            app.ExportfeaturesButton.Text = 'Export features';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisGridLayout = uigridlayout(app.AnalysisTab);
            app.AxisPanel = uipanel(app.AnalysisGridLayout);
            app.SingleAxisPanel = uipanel(app.AxisPanel);
            app.SingleAxisPanel.Visible = 'off';
            app.ManyAxisPanel = uipanel(app.AxisPanel);
            app.ManyAxisPanel.Visible = 'off';
            app.PlotButtonGroup = uibuttongroup(app.AnalysisGridLayout);
            app.PlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroupSelectionChanged, true);
            app.IntvsTButton = uitogglebutton(app.PlotButtonGroup);
            app.IntvsTButton.Tooltip = {'Intensity vs time'};
            app.IntvsTButton.Text = 'Int vs T';
            app.CumDvsTMontageButton = uitogglebutton(app.PlotButtonGroup);
            app.CumDvsTMontageButton.Tooltip = {'cumulatrive displacement vs time'};
            app.CumDvsTMontageButton.Text = 'Cum Disp vs T';
            app.DvsTButton = uitogglebutton(app.PlotButtonGroup);
            app.DvsTButton.Tooltip = {'trajectories'};
            app.DvsTButton.Text = 'Disp vs T';
            app.XvsYButton = uitogglebutton(app.PlotButtonGroup);
            app.XvsYButton.Tooltip = {'MSD vs time'};
            app.XvsYButton.Text = 'X vs Y';
            app.PlotKymoButton = uitogglebutton(app.PlotButtonGroup);
            app.PlotKymoButton.Tooltip = {'MSD vs time'};
            app.PlotKymoButton.Text = 'Kymo';
            app.MontageLabel = uilabel(app.PlotButtonGroup);
            app.MontageLabel.Tooltip = {'Plot lifetime histograms'};
            app.MontageLabel.Text = 'Montage:';
            app.LTHistButton = uitogglebutton(app.PlotButtonGroup);
            app.LTHistButton.Tooltip = {'Plot histogram of lifetimes'};
            app.LTHistButton.Text = 'Lifetime';
            app.LTHistButton.Value = true;
            app.DecayCurveButton = uitogglebutton(app.PlotButtonGroup);
            app.DecayCurveButton.Tooltip = {'Plot release curves'};
            app.DecayCurveButton.Text = 'Decay vs T';
            app.IntHistButton = uitogglebutton(app.PlotButtonGroup);
            app.IntHistButton.Tooltip = {'Plot histogram of intensities'};
            app.IntHistButton.Text = 'Int';
            app.DvsAlphaButton = uitogglebutton(app.PlotButtonGroup);
            app.DvsAlphaButton.Tooltip = {'Short term diffusivity vs anomolous exponent '};
            app.DvsAlphaButton.Text = 'D vs Alpha';
            app.MSDatTauHistButton = uitogglebutton(app.PlotButtonGroup);
            app.MSDatTauHistButton.Tooltip = {'plot histogram of mean square displacement (MSD) at a fixed lag time tau'};
            app.MSDatTauHistButton.Text = 'MSD at Tau';
            app.SpdHistButton = uitogglebutton(app.PlotButtonGroup);
            app.SpdHistButton.Tooltip = {'Plot histogram of speeds'};
            app.SpdHistButton.Text = 'Speed';
            app.DistributionsLabel = uilabel(app.PlotButtonGroup);
            app.DistributionsLabel.Text = 'Distributions:';
            app.MSDvsTauMontageButton = uitogglebutton(app.PlotButtonGroup);
            app.MSDvsTauMontageButton.Tooltip = {'MSD vs time'};
            app.MSDvsTauMontageButton.Text = 'MSD vs Tau';
            app.TimeplotsLabel = uilabel(app.PlotButtonGroup);
            app.TimeplotsLabel.Text = 'Timeplots:';
            app.CumDvsTButton = uitogglebutton(app.PlotButtonGroup);
            app.CumDvsTButton.Tooltip = {'cumulatrive displacement vs time'};
            app.CumDvsTButton.Text = 'Cum Disp vs T';
            app.MSDvsTauButton = uitogglebutton(app.PlotButtonGroup);
            app.MSDvsTauButton.Tooltip = {'MSD vs time'};
            app.MSDvsTauButton.Text = 'MSD vs Tau';
            app.LeftPlotControlPanel = uipanel(app.AnalysisGridLayout);
            app.PlotModeButtonGroup = uibuttongroup(app.LeftPlotControlPanel);
            app.PlotModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotModeButtonGroupSelectionChanged2, true);
            app.selectedButton = uiradiobutton(app.PlotModeButtonGroup);
            app.selectedButton.Text = 'selected';
            app.selectedButton.Value = true;
            app.allButton = uiradiobutton(app.PlotModeButtonGroup);
            app.allButton.Text = 'all';
            app.meanpooledButton = uiradiobutton(app.PlotModeButtonGroup);
            app.meanpooledButton.Text = 'mean/pooled';
            app.PlotmodeLabel = uilabel(app.PlotModeButtonGroup);
            app.PlotmodeLabel.Text = 'Plot mode';
            app.PlotParameterPanel = uipanel(app.LeftPlotControlPanel);
            app.ParametersLabel = uilabel(app.PlotParameterPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.Tau_maxLabel = uilabel(app.PlotParameterPanel);
            app.Tau_maxLabel.Visible = 'off';
            app.Tau_maxLabel.Text = 'MaxTau';
            app.MaxTauEditField = uieditfield(app.PlotParameterPanel, 'numeric');
            app.MaxTauEditField.Limits = [2 Inf];
            app.MaxTauEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTauEditFieldValueChanged, true);
            app.MaxTauEditField.Visible = 'off';
            app.MaxTauEditField.Value = 2;
            app.SmotthingWindowSizeLabel = uilabel(app.PlotParameterPanel);
            app.SmotthingWindowSizeLabel.Visible = 'off';
            app.SmotthingWindowSizeLabel.Text = 'SGWindow';
            app.SGWindowEditField = uieditfield(app.PlotParameterPanel, 'numeric');
            app.SGWindowEditField.Limits = [1 Inf];
            app.SGWindowEditField.Visible = 'off';
            app.SGWindowEditField.Value = 1;
            app.TauLabel = uilabel(app.PlotParameterPanel);
            app.TauLabel.Visible = 'off';
            app.TauLabel.Text = 'Tau';
            app.TauEditField = uieditfield(app.PlotParameterPanel, 'numeric');
            app.TauEditField.Limits = [0 Inf];
            app.TauEditField.ValueChangedFcn = createCallbackFcn(app, @TauEditFieldValueChanged, true);
            app.TauEditField.Visible = 'off';
            app.TauEditField.Value = 4;
            app.PlotDestButtonGroup = uibuttongroup(app.LeftPlotControlPanel);
            app.PlotDestButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotDestButtonGroupSelectionChanged, true);
            app.embeddedButton = uiradiobutton(app.PlotDestButtonGroup);
            app.embeddedButton.Text = 'embedded';
            app.embeddedButton.Value = true;
            app.floatingButton = uiradiobutton(app.PlotDestButtonGroup);
            app.floatingButton.Text = 'floating';
            app.PlotDestinationLabel = uilabel(app.PlotDestButtonGroup);
            app.PlotDestinationLabel.Text = 'Plot Destination';
            app.PlotScaleButtonGroup = uibuttongroup(app.LeftPlotControlPanel);
            app.PlotScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotScaleButtonGroupSelectionChanged, true);
            app.LogButton = uiradiobutton(app.PlotScaleButtonGroup);
            app.LogButton.Text = 'Log';
            app.LogButton.Value = true;
            app.LinearButton = uiradiobutton(app.PlotScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.PlotScaleLabel = uilabel(app.PlotScaleButtonGroup);
            app.PlotScaleLabel.Text = 'Plot Scale';
            app.Panel_2 = uipanel(app.LeftPlotControlPanel);
            app.AutoreplotCheckBox = uicheckbox(app.Panel_2);
            app.AutoreplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoreplotCheckBoxValueChanged, true);
            app.AutoreplotCheckBox.Text = 'autoplot';
            app.PlotButton = uibutton(app.Panel_2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputFilesPanel = uipanel(app.LeftPanel);
            app.InputFilesTable = uitable(app.InputFilesPanel);
            app.InputFilesTable.ColumnName = '';
            app.InputFilesTable.RowName = {};
            app.InputFilesTable.SelectionType = 'row';
            app.InputFilesTable.SelectionChangedFcn = createCallbackFcn(app, @InputFilesTableSelectionChanged, true);
            app.InputFilesTable.Multiselect = 'off';
            app.InputfilesLabel = uilabel(app.InputFilesPanel);
            app.InputfilesLabel.Text = 'Input files';
            app.ResultsPanel = uipanel(app.LeftPanel);
            app.ResultsTable = uitable(app.ResultsPanel);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.ResultsTable.SelectionChangedFcn = createCallbackFcn(app, @ResultsTableSelectionChanged, true);
            app.ParameterToVaryLabel = uilabel(app.ResultsPanel);
            app.ParameterToVaryLabel.Text = '';
            app.ResultsLabel = uilabel(app.ResultsPanel);
            app.ResultsLabel.Text = 'Trajectories';
            app.InfoPanel = uipanel(app.LeftPanel);
            app.InfoLabel = uilabel(app.InfoPanel);
            app.InfoLabel.Text = 'Info';
            app.InfoTable = uitable(app.InfoPanel);
            app.InfoTable.ColumnName = '';
            app.InfoTable.RowName = {};
            app.FiltersPanel = uipanel(app.LeftPanel);
            app.FilterByUITable = uitable(app.FiltersPanel);
            app.FilterByUITable.ColumnName = {'name'; 'min'; 'max'; 'active'};
            app.FilterByUITable.RowName = {};
            app.FilterByUITable.SelectionType = 'row';
            app.FilterByUITable.ColumnEditable = [false true true true];
            app.FilterByUITable.CellEditCallback = createCallbackFcn(app, @FilterByUITableCellEdit, true);
            app.FilterByUITable.Multiselect = 'off';
            app.ClearButton = uibutton(app.FiltersPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FilterButton = uibutton(app.FiltersPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.autofilterCheckBox = uicheckbox(app.FiltersPanel);
            app.autofilterCheckBox.Text = 'autofilter';
            app.ResultsLabel_2 = uilabel(app.LeftPanel);
            app.ResultsLabel_2.Text = 'Filter by';
            app.OpenFilePanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.OpenFilePanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.OpeninputfileButton = uibutton(app.OpenFilePanel, 'push');
            app.OpeninputfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninputfileButtonPushed, true);
            app.OpeninputfileButton.Text = 'Open input file';
            app.OpenresultsButton = uibutton(app.OpenFilePanel, 'push');
            app.OpenresultsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenresultsButtonPushed, true);
            app.OpenresultsButton.Text = 'Open results';
            app.CurrentDirectoryPanel = uipanel(app.GridLayout);
            app.currentfileLabel = uilabel(app.CurrentDirectoryPanel);
            app.currentfileLabel.Text = 'Current directory:';
            app.FilePathEditField = uieditfield(app.CurrentDirectoryPanel, 'text');
            app.FilePathEditField.Value = 'select input file to start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end